classdef DEERconstruct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DEERc                      matlab.ui.Figure
        LoadButton                 matlab.ui.control.Button
        FilenameLabel              matlab.ui.control.Label
        rPlot                      matlab.ui.control.UIAxes
        TimePlot                   matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        PopulationsTab             matlab.ui.container.Tab
        add1                       matlab.ui.control.StateButton
        rLabel                     matlab.ui.control.Label
        oner                       matlab.ui.control.Spinner
        FWHMSpinnerLabel           matlab.ui.control.Label
        oneFWHM                    matlab.ui.control.Spinner
        Spinner_3Label             matlab.ui.control.Label
        oneperc                    matlab.ui.control.Spinner
        add2                       matlab.ui.control.StateButton
        rLabel_2                   matlab.ui.control.Label
        twor                       matlab.ui.control.Spinner
        FWHMSpinner_2Label         matlab.ui.control.Label
        twoFWHM                    matlab.ui.control.Spinner
        Spinner_4Label             matlab.ui.control.Label
        twoperc                    matlab.ui.control.Spinner
        add3                       matlab.ui.control.StateButton
        rLabel_3                   matlab.ui.control.Label
        threer                     matlab.ui.control.Spinner
        FWHMSpinner_3Label         matlab.ui.control.Label
        threeFWHM                  matlab.ui.control.Spinner
        Spinner_5Label             matlab.ui.control.Label
        threeperc                  matlab.ui.control.Spinner
        add4                       matlab.ui.control.StateButton
        rLabel_4                   matlab.ui.control.Label
        fourr                      matlab.ui.control.Spinner
        FWHMSpinner_4Label         matlab.ui.control.Label
        fourFWHM                   matlab.ui.control.Spinner
        Spinner_6Label             matlab.ui.control.Label
        fourperc                   matlab.ui.control.Spinner
        add5                       matlab.ui.control.StateButton
        rLabel_5                   matlab.ui.control.Label
        fiver                      matlab.ui.control.Spinner
        FWHMSpinner_5Label         matlab.ui.control.Label
        fiveFWHM                   matlab.ui.control.Spinner
        Spinner_7Label             matlab.ui.control.Label
        fiveperc                   matlab.ui.control.Spinner
        add6                       matlab.ui.control.StateButton
        rLabel_6                   matlab.ui.control.Label
        sixr                       matlab.ui.control.Spinner
        FWHMSpinner_6Label         matlab.ui.control.Label
        sixFWHM                    matlab.ui.control.Spinner
        Spinner_8Label             matlab.ui.control.Label
        sixperc                    matlab.ui.control.Spinner
        inTimePlot                 matlab.ui.control.UIAxes
        OptimizingLabel            matlab.ui.control.Label
        OptimizePopulationsButton  matlab.ui.control.StateButton
        PrRMSDLabel                matlab.ui.control.Label
        WatchCheckBox              matlab.ui.control.CheckBox
        RevertButton               matlab.ui.control.Button
        SuppressionsTab            matlab.ui.container.Tab
        SuppressListBox            matlab.ui.control.ListBox
        suppPlot                   matlab.ui.control.UIAxes
        RMSDincreasedLabel         matlab.ui.control.Label
        RMSDR2ListBox              matlab.ui.control.ListBox
        v23Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        data={};
        stats={};
        tags={};
        dataLoaded = 'n';
    end
    
    methods (Access = private)
        
        function [Kmatrix]=Kmatrixer(app)
            
            theta=linspace(0,pi/2,length(app.data.time));
            cos_2_theta=cos(theta).^2;
            sin_theta=sin(theta');
            threecos=(3.*cos_2_theta)-1;
            const_r=326.98./app.data.dist.^3;
            
            threecos_const_r=zeros(length(threecos),length(const_r));
            for i=1:length(threecos)
                for j=1:length(const_r)
                    threecos_const_r(i,j)=threecos(i).*const_r(j)';
                end
            end
            
            Kmatrix=zeros(length(app.data.time),length(app.data.time));
            j=0;
            for i=app.data.time
                for l=sin_theta
                    j=j+1;
                    Kmatrix(j,:)=l'*cos(threecos_const_r*i);
                end
            end
            
        end
        
        function makePr(app)
            
            app.data.populations=zeros(length(app.data.dist),app.data.sumOnOff);
            k=1;
            for i=1:6
                switch app.tags.addOnOff(i)
                    case 1
                        population = (sqrt((2/pi))*(1/(app.data.sig(k)/(2*reallog(2)))))*exp(-2*(((...
                            app.data.dist-app.data.mu(k))/(app.data.sig(k)/(2*reallog(2)))).^2));
                        app.data.populations(:,k) = (population./max(population)).*app.data.eta(k);
                        k=k+1;
                end
            end
            
            if app.data.sumOnOff>1
                suppressions(app)
            end
            totalPr = sum(app.data.populations,2);
            app.stats.PrRMSD=sqrt(sum((app.data.Pr-totalPr).^2)/length(app.data.Pr));
            
            model=app.data.Kmatrix*totalPr;
            assignin("base","model",model)
            model=model./max(model);
            
            switch app.tags.optimizing
                case 'n'
                    
                    app.stats.kVtRMSD=sqrt(sum((app.data.kVt-model).^2)/length(app.data.time));
                    
                    app.stats.lst= {
                        ['RMSD: Model V(t) vs. Kernel V(t) = ',num2str(app.stats.kVtRMSD)],...
                        '----------',...
                        ['RMSD: Model P(r) vs. Data P(r)= ',num2str(app.stats.PrRMSD)]
                        };
                    set(app.RMSDR2ListBox,'Items',app.stats.lst)
                    
                    plot(app.inTimePlot,app.data.time,app.data.kVt,'LineWidth',1.5);
                    hold(app.inTimePlot,'on')
                    plot(app.inTimePlot,app.data.time,model,'LineWidth',1.5);
                    xlabel(app.inTimePlot,'time');ylabel(app.rPlot,'V(t)');
                    xlim(app.inTimePlot,[min(app.data.time) max(app.data.time)]);
                    legend(app.inTimePlot,'Kernel V(t)','Model');
                    hold(app.inTimePlot,'off')
                    
                    plot(app.rPlot,app.data.dist,app.data.Pr,'LineWidth',1.5)
                    hold(app.rPlot,'on')
                    area(app.rPlot,app.data.dist,totalPr,'FaceColor',[.9 .9 .9]);
                    for i=1:app.data.sumOnOff
                        plot(app.rPlot,app.data.dist,app.data.populations(:,i),'LineWidth',1.5)
                    end
                    xlabel(app.rPlot,'r');ylabel(app.rPlot,'P(r)');
                    xlim(app.rPlot,[min(app.data.dist) max(app.data.dist)]);
                    ylim(app.rPlot,[-0.05 max(app.data.Pr)*1.05]);
                    legend(app.rPlot,'Data P(r)','Sum of Populations');
                    hold(app.rPlot,'off')
            end
        end
        
        function res = optimize_pr(app,var)
            
            switch app.tags.stopflag
                case 'stop'
                    app.data.eta=app.data.orgeta;
                    app.data.mu=app.data.orgmu;
                    app.data.sig=app.data.orgsig;
                    makePr(app)
                    return
            end
            
            l=length(var)/3;
            mu=zeros(l,1);
            eta=zeros(l,1);
            sig=zeros(l,1);
            for k=1:l
                j=k;
                mu(k)=var(j);
                j=j+l;
                eta(k)=var(j);
                j=j+l;
                sig(k)=var(j);
            end
            
            populations=zeros(length(app.data.Pr),app.data.sumOnOff);
            for i=1:app.data.sumOnOff
                population = (sqrt((2/pi))*(1/(sig(i)/(2*reallog(2)))))*exp(-2*(((...
                    app.data.dist-mu(i))/(sig(i)/(2*reallog(2)))).^2));
                populations(:,i) = (population./max(population)).*eta(i);
            end
            
            totalPr = sum(populations,2);
            res=sum((totalPr-app.data.Pr).^2);
            
            PrRMSD=sqrt(sum((app.data.Pr-totalPr).^2)/length(app.data.Pr));
            
            switch get(app.WatchCheckBox,'Value')
                case 1
                    model=app.data.Kmatrix*totalPr;
                    model=model./max(model);
                    
                    set(app.PrRMSDLabel,'Text',['RMSD = ',num2str(PrRMSD)])
                    
                    plot(app.rPlot,app.data.dist,app.data.Pr,'LineWidth',1.5)
                    hold(app.rPlot,'on')
                    plot(app.rPlot,app.data.dist,totalPr,'LineWidth',1.5)
                    hold(app.rPlot,'off')
                    
                    plot(app.inTimePlot,app.data.time,app.data.Vt,'LineWidth',1.5);
                    hold(app.inTimePlot,'on')
                    plot(app.inTimePlot,app.data.time,model,'LineWidth',1.5);
                    hold(app.inTimePlot,'off')
                    
                    drawnow
            end
            
        end
        
        
        function  suppressions(app)
            
            [c{app.data.sumOnOff:-1:1}]=ndgrid([0,1]);
            supmat=reshape(cat(app.data.sumOnOff+1,c{:}),[],app.data.sumOnOff);
            app.stats.supmat=supmat(2:length(supmat)-1,:);
            
            RMSD(:,2)=1:1:length(app.stats.supmat);
            for n=1:length(app.stats.supmat)
                supmix=bsxfun(@times,app.data.populations,app.stats.supmat(n,:));
                suppr(:,n)=sum(supmix,2);
                supecho=app.data.Kmatrix*suppr(:,n);
                suppecho(:,n)=supecho./max(supecho);
                RMSD(n,1)=sqrt(sum((app.data.kVt-suppecho(:,n)).^2)/length(app.data.time));
            end
            app.stats.suppRMSD=sortrows(RMSD,1,'ascend');
            
            for l=1:length(app.stats.suppRMSD)
                str1{l}='Supress at: ';
                for m=1:app.data.sumOnOff
                    switch app.stats.supmat(app.stats.suppRMSD(l,2),m)
                        case 0
                            if m==1
                                str1{l}=append(str1{l},num2str(app.data.mu(m)));
                            else
                                str1{l}=append(str1{l},['  ',num2str(app.data.mu(m))]);
                            end
                    end
                end
            end
            
            app.stats.supplst=[];
            app.stats.supplst{1}='NONE';
            for k=2:length(app.stats.suppRMSD)+1
                for j=1:app.data.sumOnOff
                    app.stats.supplst{k}=['RMSD: ',num2str(app.stats.suppRMSD(k-1,1)),...
                        ' ;  ',str1{k-1}];
                end
            end
            set(app.SuppressListBox,'Items',app.stats.supplst)
            
            plot(app.suppPlot,app.data.time,app.data.kVt,'LineWidth',1.5);
            hold(app.suppPlot,'on')
            plot(app.suppPlot,app.data.time,suppecho(:,app.stats.suppRMSD(1,2)),'LineWidth',1.5);
            xlabel(app.suppPlot,'time');
            xlim(app.suppPlot,[min(app.data.time) max(app.data.time)]);
            legend(app.suppPlot,'V(t)','Suppression Model');
            hold(app.suppPlot,'off')
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Opening(app)
            scrn = get(groot,'ScreenSize');
            drawnow
            app.DEERc.Position = ...
                [scrn(3)*.07 scrn(4)*.12 scrn(3)*.8333 scrn(4)*.8];
        end

        % Button pushed function: LoadButton
        function LoadData(app, event)
            app.DEERc.Visible = 'Off';
            try
                [filenm,pathname]=uigetfile({'*.dat','*.txt'});
                [rootnm,~]=strtok(filenm,'.');
            catch
                app.DEERc.Visible = 'On';
                return
            end
            app.DEERc.Visible = 'On';
            set(app.FilenameLabel,'Text',rootnm)
            
            bckg=contains(filenm,'_bckg');
            Lcurve=contains(filenm,'_Lcurve');
            distr=contains(filenm,'_distr');
            fit=contains(filenm,'_fit');
            res=contains(filenm,'_res');
            spc=contains(filenm,'_spc');
            if bckg==1 || Lcurve==1 || distr==1 || fit==1 || res==1 || spc==1
                switch bckg
                    case 1
                        fitnm=regexprep(filenm,'bckg','fit');
                        distrnm=regexprep(filenm,'bckg','distr');
                end
                switch Lcurve
                    case 1
                        fitnm=regexprep(filenm,'Lcurve','fit');
                        distrnm=regexprep(filenm,'Lcurve','distr');
                end
                switch distr
                    case 1
                        fitnm=regexprep(filenm,'distr','fit');
                        distrnm=filenm;
                end
                switch fit
                    case 1
                        fitnm=filenm;
                        distrnm=regexprep(filenm,'fit','distr');
                end
                switch res
                    case 1
                        fitnm=regexprep(filenm,'res','fit');
                        distrnm=regexprep(filenm,'res','distr');
                end
                switch spc
                    case 1
                        fitnm=regexprep(filenm,'spc','fit');
                        distrnm=regexprep(filenm,'spc','distr');
                end
                
                td_data=dlmread(strcat(pathname,filesep,fitnm));
                distr_data=dlmread(strcat(pathname,filesep,distrnm));
                
                if length(td_data)>length(distr_data)
                    app.data.time=td_data(1:length(distr_data),1)';
                    Vt=td_data(1:length(distr_data),2);
                    tkrVt=td_data(1:length(distr_data),3);
                    app.data.dist=distr_data(:,1)';
                    app.data.Pr=(distr_data(:,2)./max(distr_data(:,2)));
                end
                if length(td_data)<length(distr_data)
                    app.data.time=td_data(:,1)';
                    Vt=td_data(:,2);
                    tkrVt=td_data(:,3);
                    app.data.dist=distr_data(1:length(td_data),1)';
                    app.data.Pr=(distr_data(1:length(td_data),2)./max(distr_data(1:length(td_data),2)));
                end
                if length(td_data)==length(distr_data)
                    app.data.time=td_data(:,1)';
                    Vt=td_data(:,2);
                    tkrVt=td_data(:,3);
                    app.data.dist=distr_data(:,1)';
                    app.data.Pr=(distr_data(:,2)/max(distr_data(:,2)));
                end
                
            else
                indata=dlmread(strcat(pathname,filesep,filenm));
                
                app.data.dist=indata(:,1)';
                Pr=indata(:,2);
                app.data.Pr=Pr./max(Pr);
                app.data.time=indata(:,3)';
                Vt=indata(:,4);
                tkrVt=indata(:,5);
            end
            
            [app.data.Kmatrix]=Kmatrixer(app);
            
            kVt=app.data.Kmatrix*app.data.Pr;
            app.data.kVt=(kVt./max(kVt));
            
            p=linspace(0,1,1000);
            ss=zeros(length(p),1);
            for i=1:length(p)
                temp1=((tkrVt-1)/p(i)+1)-app.data.kVt;
                ss(i)=sum(temp1.*temp1);
            end
            [~,i_opt]=min(ss);
            sclmod=p(i_opt);
            app.data.tkrVt=((tkrVt-1)/sclmod)+1;
            app.data.Vt=((Vt-1)/sclmod)+1;
            
            set(app.SuppressListBox,'Items',{})
            set(app.add1,'Value',0)
            set(app.add2,'Value',0)
            set(app.add3,'Value',0)
            set(app.add4,'Value',0)
            set(app.add5,'Value',0)
            set(app.add6,'Value',0)
            
            plot(app.rPlot,app.data.dist,app.data.Pr,'LineWidth',1.5)
            xlabel(app.rPlot,'r');ylabel(app.rPlot,'P(r)');
            xlim(app.rPlot,[min(app.data.dist) max(app.data.dist)]);
            ylim(app.rPlot,[-0.05 max(app.data.Pr)*1.05]);
            legend(app.rPlot,'Data P(r)');
            
            plot(app.TimePlot,app.data.time,app.data.Vt,'LineWidth',1.5);
            hold(app.TimePlot,'on')
            plot(app.TimePlot,app.data.time,app.data.tkrVt,'LineWidth',1.5);
            plot(app.TimePlot,app.data.time,app.data.kVt,'LineWidth',1.5);
            hold(app.TimePlot,'off')
            xlabel(app.TimePlot,'time');ylabel(app.rPlot,'V(t)');
            xlim(app.TimePlot,[min(app.data.time) max(app.data.time)]);
            ylim(app.TimePlot,[-0.2 max(app.data.Vt)*1.05]);
            legend(app.TimePlot,'Data V(t)','Fit V(t)','Kernel V(t)');
            
            set(app.PrRMSDLabel,'Visible','off')
            set(app.OptimizingLabel,'Visible','off')
            app.tags.optimizing='n';
            set(app.RMSDincreasedLabel,'Visible','off')
            
            app.dataLoaded = 'y';
            
        end

        % Value changed function: add1, add2, add3, add4, add5, 
        % add6, fiveFWHM, fiveperc, fiver, fourFWHM, fourperc, 
        % fourr, oneFWHM, oneperc, oner, sixFWHM, sixperc, sixr, 
        % threeFWHM, threeperc, threer, twoFWHM, twoperc, twor
        function AddPopulation(app, event)
            
            switch app.dataLoaded
                case 'n'
                    set(app.RMSDR2ListBox,'Items',{'NO DATA'})
                    set(app.add1,'Value',0)
                    set(app.add2,'Value',0)
                    set(app.add3,'Value',0)
                    set(app.add4,'Value',0)
                    set(app.add5,'Value',0)
                    set(app.add6,'Value',0)
                    return
            end
            
            app.tags.addOnOff=[app.add1.Value,app.add2.Value,app.add3.Value,...
                app.add4.Value,app.add5.Value,app.add6.Value];
            app.data.sumOnOff=sum(app.tags.addOnOff);
            if app.data.sumOnOff==0
                plot(app.rPlot,app.data.dist,app.data.Pr,'LineWidth',1.5)
                xlabel(app.rPlot,'r');ylabel(app.rPlot,'P(r)');
                xlim(app.rPlot,[min(app.data.dist) max(app.data.dist)]);
                ylim(app.rPlot,[-0.05 max(app.data.Pr)*1.05]);
                legend(app.rPlot,'Data P(r)');
                
                plot(app.TimePlot,app.data.time,app.data.Vt,'LineWidth',1.5);
                hold(app.TimePlot,'on')
                plot(app.TimePlot,app.data.time,app.data.tkrVt,'LineWidth',1.5);
                plot(app.TimePlot,app.data.time,app.data.kVt,'LineWidth',1.5);
                hold(app.TimePlot,'off')
                xlabel(app.TimePlot,'time');ylabel(app.rPlot,'V(t)');
                xlim(app.TimePlot,[min(app.data.time) max(app.data.time)]);
                ylim(app.TimePlot,[-0.2 max(app.data.Vt)*1.05]);
                legend(app.TimePlot,'Data V(t)','Fit V(t)','Kernel V(t)');
                return
            end
            
            mu=[app.oner.Value,app.twor.Value,app.threer.Value,...
                app.fourr.Value,app.fiver.Value,app.sixr.Value];
            
            sig=[app.oneFWHM.Value,app.twoFWHM.Value,app.threeFWHM.Value,...
                app.fourFWHM.Value,app.fiveFWHM.Value,app.sixFWHM.Value];
            
            eta=[app.oneperc.Value,app.twoperc.Value,app.threeperc.Value,...
                app.fourperc.Value,app.fiveperc.Value,app.sixperc.Value]./100;
            
            app.data.eta=[];
            app.data.mu=[];
            app.data.sig=[];
            
            k=1;
            for i=1:6
                switch app.tags.addOnOff(i)
                    case 1
                        app.data.eta(k)=eta(i);
                        app.data.mu(k)=mu(i);
                        app.data.sig(k)=sig(i);
                        k=k+1;
                end
            end
            app.tags.optimizing='n';
            makePr(app);
            
            set(app.PrRMSDLabel,'Visible','off')
            set(app.OptimizingLabel,'Visible','off')
            set(app.RMSDincreasedLabel,'Visible','off')
            
        end

        % Value changed function: OptimizePopulationsButton
        function optimizeModel(app, event)
            
            switch app.dataLoaded
                case 'n'
                    set(app.RMSDR2ListBox,'Items',{'NO DATA'})
                    set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
                    return
            end
            
            switch get(app.OptimizePopulationsButton,'Value')
                case 1
                    app.tags.stopflag='go';
                    set(app.OptimizePopulationsButton,'Text','Stop Optimization')
                    drawnow
                    app.tags.addOnOff=[app.add1.Value,app.add2.Value,app.add3.Value,...
                        app.add4.Value,app.add5.Value,app.add6.Value];
                    app.data.sumOnOff=sum(app.tags.addOnOff);
                    if app.data.sumOnOff==0
                        set(app.OptimizingLabel,'Visible','off')
                        set(app.PrRMSDLabel,'Visible','off')
                        set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
                        return
                    end
                    
                    mu=[app.oner.Value,app.twor.Value,app.threer.Value,...
                        app.fourr.Value,app.fiver.Value,app.sixr.Value];
                    
                    sig=[app.oneFWHM.Value,app.twoFWHM.Value,app.threeFWHM.Value,...
                        app.fourFWHM.Value,app.fiveFWHM.Value,app.sixFWHM.Value];
                    
                    eta=[app.oneperc.Value,app.twoperc.Value,app.threeperc.Value,...
                        app.fourperc.Value,app.fiveperc.Value,app.sixperc.Value]./100;
                    
                    app.data.orgeta=[];
                    app.data.orgmu=[];
                    app.data.orgsig=[];
                    
                    k=1;
                    for i=1:6
                        switch app.tags.addOnOff(i)
                            case 1
                                app.data.orgeta(k)=eta(i);
                                app.data.orgmu(k)=mu(i);
                                app.data.orgsig(k)=sig(i);
                                k=k+1;
                        end
                    end
                    
                    if app.stats.PrRMSD>0.1
                        set(app.OptimizingLabel,'Visible','on','Text','Not close enough yet')
                        set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
                        return
                    else
                        orgR2=app.stats.PrRMSD;
                        x0=cat(2,app.data.orgmu,app.data.orgeta,app.data.orgsig);
                    end
                    
                    set(app.OptimizingLabel,'Visible','on','Text','Optimizing...')
                    drawnow
                    try
                        set(app.PrRMSDLabel,'Visible','on')
                        [opt]=fminsearch(@app.optimize_pr, x0);
                    catch
                        switch app.tags.stopflag
                            case 'stop'
                                set(app.OptimizingLabel,'Text','optimization stopped')
                            case 'go'
                                set(app.OptimizingLabel,'Text','optimization failed')
                        end
                        set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
                        set(app.PrRMSDLabel,'Visible','off')
                        return
                    end
                    
                    l=length(opt)/3;
                    app.data.eta=zeros(1,l);
                    app.data.mu=zeros(1,l);
                    app.data.sig=zeros(1,l);
                    chk=0;
                    for k=1:l
                        j=k;
                        app.data.mu(k)=opt(j);
                        if app.data.mu(k)>8
                            chk=chk+1;
                        end
                        j=j+l;
                        app.data.eta(k)=opt(j);
                        if app.data.eta(k)<0
                            chk=chk+1;
                        end
                        j=j+l;
                        app.data.sig(k)=opt(j);
                        if app.data.sig(k)>8
                            chk=chk+1;
                        end
                    end
                    
                    app.tags.optimizing='y';
                    makePr(app)
                    if app.stats.PrRMSD<orgR2 && chk==0
                        set(app.PrRMSDLabel,'Visible','on','Text',['P(r) RMSD improved by ',...
                            num2str(((orgR2-app.stats.PrRMSD)/orgR2)*100),' %'])
                        parMatrix =zeros(6,3);
                        k=1;
                        for i=1:6
                            switch app.tags.addOnOff(i)
                                case 1
                                    parMatrix(i,1)=app.data.mu(k);
                                    parMatrix(i,2)=app.data.sig(k);
                                    parMatrix(i,3)=app.data.eta(k);
                                    k=k+1;
                                case 0
                                    parMatrix(i,1)=mu(i);
                                    parMatrix(i,2)=sig(i);
                                    parMatrix(i,3)=eta(i);
                            end
                        end
                        %assignin("base","parMatrix",parMatrix)
                        
                        set(app.oner,'Value',parMatrix(1,1))
                        set(app.oneFWHM,'Value',parMatrix(1,2))
                        set(app.oneperc,'Value',parMatrix(1,3)*100)
                        set(app.twor,'Value',parMatrix(2,1))
                        set(app.twoFWHM,'Value',parMatrix(2,2))
                        set(app.twoperc,'Value',parMatrix(2,3)*100)
                        set(app.threer,'Value',parMatrix(3,1))
                        set(app.threeFWHM,'Value',parMatrix(3,2))
                        set(app.threeperc,'Value',parMatrix(3,3)*100)
                        set(app.fourr,'Value',parMatrix(4,1))
                        set(app.fourFWHM,'Value',parMatrix(4,2))
                        set(app.fourperc,'Value',parMatrix(4,3)*100)
                        set(app.fiver,'Value',parMatrix(5,1))
                        set(app.fiveFWHM,'Value',parMatrix(5,2))
                        set(app.fiveperc,'Value',parMatrix(5,3)*100)
                        set(app.sixr,'Value',parMatrix(6,1))
                        set(app.sixFWHM,'Value',parMatrix(6,2))
                        set(app.sixperc,'Value',parMatrix(6,3)*100)
                        
                        app.tags.optimizing='n';
                        makePr(app)
                        
                        set(app.OptimizingLabel,'Text','Improved!')
                    else
                        app.data.eta=app.data.orgeta;
                        app.data.mu=app.data.orgmu;
                        app.data.sig=app.data.orgsig;
                        app.tags.optimizing='n';
                        makePr(app)
                        set(app.OptimizingLabel,'Text','not improved.')
                        set(app.PrRMSDLabel,'Visible','off')
                    end
                    set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
                case 0
                    app.tags.stopflag='stop';
            end
            
        end

        % Value changed function: SuppressListBox
        function suppressSelect(app, event)
            
            switch app.dataLoaded
                case 'n'
                    set(app.RMSDR2ListBox,'Items',{'NO DATA'})
                    return
            end
            
            value = app.SuppressListBox.Value;
            set(app.RMSDincreasedLabel,'Visible','on')
            for i=1:length(app.stats.supplst)
                switch value
                    case 'NONE'
                        suppecho=app.data.kVt;
                        suppr=sum(app.data.populations,2);
                        RMSD=app.stats.kVtRMSD;
                    otherwise
                        switch app.stats.supplst{i}
                            case value
                                supmix=bsxfun(@times,app.data.populations,app.stats.supmat(app.stats.suppRMSD(i-1,2),:));
                                suppr=sum(supmix,2);
                                supecho=app.data.Kmatrix*suppr;
                                suppecho=supecho./max(supecho);
                                
                                RMSD=sqrt(sum((app.data.kVt-suppecho).^2)/length(app.data.time));
                        end
                end
            end
            
            set(app.RMSDincreasedLabel,'Text',...
                ['RMSD changes by  ',num2str(((RMSD-app.stats.kVtRMSD)/app.stats.kVtRMSD)*100),' %'])
            plot(app.suppPlot,app.data.time,app.data.kVt,'LineWidth',1.5);
            hold(app.suppPlot,'on')
            plot(app.suppPlot,app.data.time,suppecho,'LineWidth',1.5);
            xlabel(app.suppPlot,'time');ylabel(app.rPlot,'V(t)');
            xlim(app.suppPlot,[min(app.data.time) max(app.data.time)]);
            legend(app.suppPlot,'Kernel V(t)','Suppression Model');
            hold(app.suppPlot,'off')
            
            plot(app.rPlot,app.data.dist,app.data.Pr,'LineWidth',1.5)
            hold(app.rPlot,'on')
            area(app.rPlot,app.data.dist,suppr,'FaceColor',[.9 .9 .9]);
            xlabel(app.rPlot,'r');ylabel(app.rPlot,'P(r)');
            xlim(app.rPlot,[min(app.data.dist) max(app.data.dist)]);
            ylim(app.rPlot,[-0.05 max(app.data.Pr)*1.05]);
            legend(app.rPlot,'Data P(r)','Suppression Model');
            hold(app.rPlot,'off')
            
        end

        % Button pushed function: RevertButton
        function revertOpt(app, event)
            app.data.eta=app.data.orgeta;
            app.data.mu=app.data.orgmu;
            app.data.sig=app.data.orgsig;
            app.tags.optimizing='n';
            makePr(app)
            set(app.OptimizePopulationsButton,'Value',0,'Text','Optimize Model')
            set(app.OptimizingLabel,'Visible','off')
            set(app.PrRMSDLabel,'Visible','off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DEERc = uifigure('Visible', 'off');
            app.DEERc.Name = 'UI Figure';
            app.LoadButton = uibutton(app.DEERc, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadButton.Text = 'Load';
            app.FilenameLabel = uilabel(app.DEERc);
            app.FilenameLabel.Text = 'Filename';
            app.rPlot = uiaxes(app.DEERc);
            title(app.rPlot, '')
            xlabel(app.rPlot, '')
            ylabel(app.rPlot, '')
            app.rPlot.YTick = [];
            app.TimePlot = uiaxes(app.DEERc);
            title(app.TimePlot, '')
            xlabel(app.TimePlot, '')
            ylabel(app.TimePlot, '')
            app.TimePlot.YTick = [];
            app.TabGroup = uitabgroup(app.DEERc);
            app.PopulationsTab = uitab(app.TabGroup);
            app.PopulationsTab.Title = 'Populations';
            app.add1 = uibutton(app.PopulationsTab, 'state');
            app.add1.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add1.Text = 'Add';
            app.rLabel = uilabel(app.PopulationsTab);
            app.rLabel.Text = 'r';
            app.oner = uispinner(app.PopulationsTab);
            app.oner.Step = 0.05;
            app.oner.Limits = [0 10];
            app.oner.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.oner.Value = 1.45;
            app.FWHMSpinnerLabel = uilabel(app.PopulationsTab);
            app.FWHMSpinnerLabel.Text = 'FWHM';
            app.oneFWHM = uispinner(app.PopulationsTab);
            app.oneFWHM.Step = 0.1;
            app.oneFWHM.Limits = [0 3];
            app.oneFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.oneFWHM.Value = 0.5;
            app.Spinner_3Label = uilabel(app.PopulationsTab);
            app.Spinner_3Label.Text = '%';
            app.oneperc = uispinner(app.PopulationsTab);
            app.oneperc.Limits = [0 150];
            app.oneperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.oneperc.Value = 20;
            app.add2 = uibutton(app.PopulationsTab, 'state');
            app.add2.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add2.Text = 'Add';
            app.rLabel_2 = uilabel(app.PopulationsTab);
            app.rLabel_2.Text = 'r ';
            app.twor = uispinner(app.PopulationsTab);
            app.twor.Step = 0.05;
            app.twor.Limits = [0 10];
            app.twor.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.twor.Value = 2.5;
            app.FWHMSpinner_2Label = uilabel(app.PopulationsTab);
            app.FWHMSpinner_2Label.Text = 'FWHM';
            app.twoFWHM = uispinner(app.PopulationsTab);
            app.twoFWHM.Step = 0.1;
            app.twoFWHM.Limits = [0 3];
            app.twoFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.twoFWHM.Value = 0.3;
            app.Spinner_4Label = uilabel(app.PopulationsTab);
            app.Spinner_4Label.Text = '%';
            app.twoperc = uispinner(app.PopulationsTab);
            app.twoperc.Limits = [0 150];
            app.twoperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.twoperc.Value = 16;
            app.add3 = uibutton(app.PopulationsTab, 'state');
            app.add3.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add3.Text = 'Add';
            app.rLabel_3 = uilabel(app.PopulationsTab);
            app.rLabel_3.Text = 'r';
            app.threer = uispinner(app.PopulationsTab);
            app.threer.Step = 0.05;
            app.threer.Limits = [0 10];
            app.threer.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.threer.Value = 3.3;
            app.FWHMSpinner_3Label = uilabel(app.PopulationsTab);
            app.FWHMSpinner_3Label.Text = 'FWHM';
            app.threeFWHM = uispinner(app.PopulationsTab);
            app.threeFWHM.Step = 0.1;
            app.threeFWHM.Limits = [0 3];
            app.threeFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.threeFWHM.Value = 0.5;
            app.Spinner_5Label = uilabel(app.PopulationsTab);
            app.Spinner_5Label.Text = '%';
            app.threeperc = uispinner(app.PopulationsTab);
            app.threeperc.Limits = [0 150];
            app.threeperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.threeperc.Value = 100;
            app.add4 = uibutton(app.PopulationsTab, 'state');
            app.add4.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add4.Text = 'Add';
            app.rLabel_4 = uilabel(app.PopulationsTab);
            app.rLabel_4.Text = 'r';
            app.fourr = uispinner(app.PopulationsTab);
            app.fourr.Step = 0.05;
            app.fourr.Limits = [0 10];
            app.fourr.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fourr.Value = 4.2;
            app.FWHMSpinner_4Label = uilabel(app.PopulationsTab);
            app.FWHMSpinner_4Label.Text = 'FWHM';
            app.fourFWHM = uispinner(app.PopulationsTab);
            app.fourFWHM.Step = 0.1;
            app.fourFWHM.Limits = [0 3];
            app.fourFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fourFWHM.Value = 0.5;
            app.Spinner_6Label = uilabel(app.PopulationsTab);
            app.Spinner_6Label.Text = '%';
            app.fourperc = uispinner(app.PopulationsTab);
            app.fourperc.Limits = [0 150];
            app.fourperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fourperc.Value = 20;
            app.add5 = uibutton(app.PopulationsTab, 'state');
            app.add5.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add5.Text = 'Add';
            app.rLabel_5 = uilabel(app.PopulationsTab);
            app.rLabel_5.Text = 'r ';
            app.fiver = uispinner(app.PopulationsTab);
            app.fiver.Step = 0.05;
            app.fiver.Limits = [0 10];
            app.fiver.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fiver.Value = 5;
            app.FWHMSpinner_5Label = uilabel(app.PopulationsTab);
            app.FWHMSpinner_5Label.Text = 'FWHM';
            app.fiveFWHM = uispinner(app.PopulationsTab);
            app.fiveFWHM.Step = 0.1;
            app.fiveFWHM.Limits = [0 3];
            app.fiveFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fiveFWHM.Value = 0.45;
            app.Spinner_7Label = uilabel(app.PopulationsTab);
            app.Spinner_7Label.Text = '%';
            app.fiveperc = uispinner(app.PopulationsTab);
            app.fiveperc.Limits = [0 150];
            app.fiveperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.fiveperc.Value = 5;
            app.add6 = uibutton(app.PopulationsTab, 'state');
            app.add6.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.add6.Text = 'Add';
            app.rLabel_6 = uilabel(app.PopulationsTab);
            app.rLabel_6.Text = 'r';
            app.sixr = uispinner(app.PopulationsTab);
            app.sixr.Step = 0.05;
            app.sixr.Limits = [0 10];
            app.sixr.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.sixr.Value = 6.1;
            app.FWHMSpinner_6Label = uilabel(app.PopulationsTab);
            app.FWHMSpinner_6Label.Text = 'FWHM';
            app.sixFWHM = uispinner(app.PopulationsTab);
            app.sixFWHM.Step = 0.1;
            app.sixFWHM.Limits = [0 3];
            app.sixFWHM.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.sixFWHM.Value = 0.5;
            app.Spinner_8Label = uilabel(app.PopulationsTab);
            app.Spinner_8Label.Text = '%';
            app.sixperc = uispinner(app.PopulationsTab);
            app.sixperc.Limits = [0 150];
            app.sixperc.ValueChangedFcn = createCallbackFcn(app, @AddPopulation, true);
            app.sixperc.Value = 6;
            app.inTimePlot = uiaxes(app.PopulationsTab);
            title(app.inTimePlot, '')
            xlabel(app.inTimePlot, '')
            ylabel(app.inTimePlot, '')
            app.inTimePlot.YTick = [];
            app.OptimizingLabel = uilabel(app.PopulationsTab);
            app.OptimizingLabel.Visible = 'off';
            app.OptimizingLabel.Text = 'Optimizing....';
            app.OptimizePopulationsButton = uibutton(app.PopulationsTab, 'state');
            app.OptimizePopulationsButton.ValueChangedFcn = createCallbackFcn(app, @optimizeModel, true);
            app.OptimizePopulationsButton.Text = 'Optimize Populations';
            app.PrRMSDLabel = uilabel(app.PopulationsTab);
            app.PrRMSDLabel.Visible = 'off';
            app.PrRMSDLabel.Text = 'R squared =';
            app.WatchCheckBox = uicheckbox(app.PopulationsTab);
            app.WatchCheckBox.Text = ' Watch';
            app.RevertButton = uibutton(app.PopulationsTab, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @revertOpt, true);
            app.RevertButton.Text = 'Revert';
            app.SuppressionsTab = uitab(app.TabGroup);
            app.SuppressionsTab.Title = 'Suppressions';
            app.SuppressListBox = uilistbox(app.SuppressionsTab);
            app.SuppressListBox.Items = {};
            app.SuppressListBox.ValueChangedFcn = createCallbackFcn(app, @suppressSelect, true);
            app.SuppressListBox.Value = {};
            app.suppPlot = uiaxes(app.SuppressionsTab);
            title(app.suppPlot, '')
            xlabel(app.suppPlot, '')
            ylabel(app.suppPlot, '')
            app.suppPlot.YTick = [];
            app.RMSDincreasedLabel = uilabel(app.SuppressionsTab);
            app.RMSDincreasedLabel.Visible = 'off';
            app.RMSDincreasedLabel.Text = 'RMSD changed by                       %';
            app.RMSDR2ListBox = uilistbox(app.DEERc);
            app.RMSDR2ListBox.Items = {};
            app.RMSDR2ListBox.Value = {};
            app.v23Label = uilabel(app.DEERc);
            app.v23Label.Text = 'v 2.3';
            % Show the figure after all components are created
            app.DEERc.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DEERconstruct

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DEERc)

            % Execute the startup function
            runStartupFcn(app, @Opening)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DEERc)
        end
    end
end