classdef iEPR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        exponFitter                matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        STPRELDORTab               matlab.ui.container.Tab
        FitOpt_Dropdown            matlab.ui.control.DropDown
        OptimizingLabel            matlab.ui.control.Label
        FitSTPRELDORButton         matlab.ui.control.Button
        FunEvalsEditFieldLabel     matlab.ui.control.Label
        FunEvalsEditField          matlab.ui.control.NumericEditField
        STPRButtonGroup            matlab.ui.container.ButtonGroup
        STPRBi                     matlab.ui.control.RadioButton
        STPRTri                    matlab.ui.control.RadioButton
        STPRListBox                matlab.ui.control.ListBox
        PumpButtonGroup            matlab.ui.container.ButtonGroup
        s1Button                   matlab.ui.control.RadioButton
        s2Button                   matlab.ui.control.RadioButton
        s3Button                   matlab.ui.control.RadioButton
        DetectButtonGroup          matlab.ui.container.ButtonGroup
        s1Button_2                 matlab.ui.control.RadioButton
        s2Button_2                 matlab.ui.control.RadioButton
        s3Button_2                 matlab.ui.control.RadioButton
        ResetButton_2              matlab.ui.control.Button
        AdjustSTPRTab              matlab.ui.container.Tab
        AiSlider                   matlab.ui.control.Slider
        CiSlider                   matlab.ui.control.Slider
        BiSlider                   matlab.ui.control.Slider
        weSlider                   matlab.ui.control.Slider
        KxSlider                   matlab.ui.control.Slider
        wnSlider                   matlab.ui.control.Slider
        weEditFieldLabel           matlab.ui.control.Label
        weEditField                matlab.ui.control.NumericEditField
        wnEditFieldLabel           matlab.ui.control.Label
        wnEditField                matlab.ui.control.NumericEditField
        KxEditFieldLabel           matlab.ui.control.Label
        KxEditField                matlab.ui.control.NumericEditField
        AiEditFieldLabel           matlab.ui.control.Label
        AiEditField                matlab.ui.control.NumericEditField
        BiEditFieldLabel           matlab.ui.control.Label
        BiEditField                matlab.ui.control.NumericEditField
        CiEditFieldLabel           matlab.ui.control.Label
        CiEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        k1nsEditFieldLabel         matlab.ui.control.Label
        k1nsEditField              matlab.ui.control.NumericEditField
        taupsEditFieldLabel        matlab.ui.control.Label
        taupsEditField             matlab.ui.control.NumericEditField
        CenterSlidersButton        matlab.ui.control.Button
        RefitButton                matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        fieldmTEditFieldLabel      matlab.ui.control.Label
        fieldmTEditField           matlab.ui.control.NumericEditField
        PhaseTab                   matlab.ui.container.Tab
        PhaseradLabel              matlab.ui.control.Label
        PhaseCorrectSlider         matlab.ui.control.Slider
        PhaseangleEditFieldLabel   matlab.ui.control.Label
        PhaseangleEditField        matlab.ui.control.NumericEditField
        PhaseCorrectEditField      matlab.ui.control.NumericEditField
        ResetPhaseButton           matlab.ui.control.Button
        ShowImaginaryButton        matlab.ui.control.StateButton
        Rotateby180Button          matlab.ui.control.Button
        Rotateby90Button           matlab.ui.control.Button
        SwitchReImButton           matlab.ui.control.Button
        PhaseOptionDropDown        matlab.ui.control.DropDown
        RelaxationsTab             matlab.ui.container.Tab
        InversionRecoveryButton    matlab.ui.control.Button
        SpinSpinButton             matlab.ui.control.Button
        InvRecovEdit               matlab.ui.control.NumericEditField
        SpinSpinEdit               matlab.ui.control.NumericEditField
        SaturationRecoveryButton   matlab.ui.control.Button
        SatRecovEdit               matlab.ui.control.NumericEditField
        RelaxDropDown              matlab.ui.control.DropDown
        InvFittingLabel            matlab.ui.control.Label
        SatFittingLabel            matlab.ui.control.Label
        SpinFittingLabel           matlab.ui.control.Label
        AverageT2EditFieldLabel    matlab.ui.control.Label
        AverageT2EditField         matlab.ui.control.NumericEditField
        AverageT1EditFieldLabel    matlab.ui.control.Label
        AverageSatT1EditField      matlab.ui.control.NumericEditField
        AverageT1EditField_2Label  matlab.ui.control.Label
        AverageInvT1EditField      matlab.ui.control.NumericEditField
        UnitsLabel                 matlab.ui.control.Label
        tT2EditFieldLabel          matlab.ui.control.Label
        T2expoEditField            matlab.ui.control.NumericEditField
        DDataLabel                 matlab.ui.control.Label
        ExponentialFitTab          matlab.ui.container.Tab
        ExponentialButtonGroup     matlab.ui.container.ButtonGroup
        MonoButton                 matlab.ui.control.RadioButton
        BiButton                   matlab.ui.control.RadioButton
        FitButton                  matlab.ui.control.Button
        Coeff1EditFieldLabel       matlab.ui.control.Label
        Coeff1EditField            matlab.ui.control.NumericEditField
        Coeff2EditFieldLabel       matlab.ui.control.Label
        Coeff2EditField            matlab.ui.control.NumericEditField
        tau1Label                  matlab.ui.control.Label
        tau1EditField              matlab.ui.control.NumericEditField
        tau2Label                  matlab.ui.control.Label
        tau2EditField              matlab.ui.control.NumericEditField
        PlotButton                 matlab.ui.control.Button
        offsetEditFieldLabel       matlab.ui.control.Label
        offsetEditField            matlab.ui.control.NumericEditField
        expFitDropDown             matlab.ui.control.DropDown
        OptimizingLabel_2          matlab.ui.control.Label
        ToolsTab                   matlab.ui.container.Tab
        PolyEditField              matlab.ui.control.NumericEditField
        DimButton                  matlab.ui.control.StateButton
        DimLabel                   matlab.ui.control.Label
        PolyCorrectButton          matlab.ui.control.Button
        RevertButton               matlab.ui.control.Button
        ToWorkspaceButton          matlab.ui.control.Button
        LeftshiftSpinnerLabel      matlab.ui.control.Label
        LeftshiftSpinner           matlab.ui.control.Spinner
        SmoothingSpinnerLabel      matlab.ui.control.Label
        SmoothingSpinner           matlab.ui.control.Spinner
        FieldGLabel                matlab.ui.control.Label
        lowEditField               matlab.ui.control.NumericEditField
        middleEditField            matlab.ui.control.NumericEditField
        highEditField              matlab.ui.control.NumericEditField
        lowLabel                   matlab.ui.control.Label
        highLabel                  matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        OrderLabel                 matlab.ui.control.Label
        midLabel                   matlab.ui.control.Label
        highLabel_3                matlab.ui.control.Label
        midLabel_3                 matlab.ui.control.Label
        lowLabel_3                 matlab.ui.control.Label
        highLabel_2                matlab.ui.control.Label
        midLabel_2                 matlab.ui.control.Label
        lowLabel_2                 matlab.ui.control.Label
        SliceSpinner               matlab.ui.control.Spinner
        ofLabel                    matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        Label                      matlab.ui.control.Label
        v02Label                   matlab.ui.control.Label
        SliceLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        data = [];
        expfit = [];
        tags = [];
        data_exists = 'n';
        
    end
    
    methods (Access = private)
        
        function [B0,spec,pars,scnD] = opendata(~,name,exten)
            switch exten
                case '.DTA'
                    [B,spc,pars]=eprload(name);
                    typ='Bruker DTA';
                case '.DSC'
                    [B,spc,pars]=eprload(name);
                    typ='Bruker DTA';
                case '.YGF'
                    name=replace(name,'YGF','DTA');
                    [B,spc,pars]=eprload(name);
                    typ='Bruker DTA';
                case '.spc'
                    [B,spcH,pars]=eprload(name);
                    if iscell(B)
                        B{1,2}=B{1,2}';
                        spc=spcH;
                    else
                        B=B';
                        spc=spcH';
                    end
                    typ='Bruker spc';
                case '.par'
                    [B,spcH,pars]=eprload(name);
                    if iscell(B)
                        B{1,2}=B{1,2}';
                        spc=spcH;
                    else
                        B=B';
                        spc=spcH';
                    end
                    typ='Bruker spc';
                case '.ESR'
                    [B,spc,pars]=eprload(name);
                    typ='ActiveSpectrum';
                case '.xml'
                    [BNaN,spcNaN,pars]=eprload(name);
                    BNaN=BNaN.*10;
                    m=1;
                    for i=1:length(BNaN)
                        if ~isnan(BNaN(i)) && BNaN(i)~=0
                            Bm(m,1)=BNaN(i);
                            spcm(m,1)=spcNaN(i);
                            m=m+1;
                        else
                        end
                    end
                    B=Bm;
                    spc=spcm;
                    typ='magnettech';
                otherwise
                    original=readmatrix(name);
                    B=original(:,1);
                    spc=original(:,2:end);
                    typ='ascii';
            end
            
            if length(spc(1,:))>1
                switch typ
                    case 'ascii'
                        B_(:,1)=B;
                        scnD1=1:1:length(spc(1,:));
                    otherwise
                        B_(:,1)=B{1,1};
                        scnD1=B{1,2};
                        
                end
                k=1;
                %assignin("base",'spc',spc)
                for i=1:length(spc(1,:))
                    if sum(abs(spc(:,i)))~=0
                        spec(:,k)=spc(:,i);
                        scnD(k)=scnD1(i);
                        k=k+1;
                    else
                    end
                end
                %assignin("base",'spk',spk)
            else
                B_=B;
                spec=spc;
                scnD=[];
            end
            
            B0=B_;
            for i=2:length(B_)+1
                if i>=length(B_)
                    break
                else
                    if B_(i)==B_(i-1) || B_(i)==B_(i+1)
                        B0(i,1)=(B_(i-1)+B_(i+1))/2;
                    else
                        B0(i,1)=B_(i);
                    end
                end
            end
            %spec = spec./max(spec(:));
        end
        
        
        function res = mono_exp_func(app,vars)
            
            ydata = (vars(1)*exp(-1*app.data.xaxis./vars(2))) +...
                vars(3);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function res = bi_exp_func(app,vars)
            
            ydata = (vars(1)*exp(-1*app.data.xaxis./vars(2))) +...
                (vars(3)*exp(-1*app.data.xaxis./vars(4))) + vars(5);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function res = bi_func_wn(app,vars)
            global N
            
            ydata = vars(1)*exp(-1*(2*vars(3)).*app.data.xaxis) +...
                vars(2)*exp(-1*(2*vars(3) + 3*vars(4) +...
                vars(5)*N).*app.data.xaxis);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function res = tri_func_wn(app,vars)
            global N
            
            ydata = vars(1)*exp(-1*(2*vars(4)).*app.data.xaxis) +...
                vars(2)*exp(-1*(2*vars(4) + 3*vars(5) +...
                vars(6)*N).*app.data.xaxis) +...
                vars(3)*exp(-1*(2*vars(4) + vars(5) +...
                vars(6)*N).*app.data.xaxis);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function res = bi_func(app,vars)
            global N wn_calced
            
            ydata = vars(1)*exp(-1*(2*vars(3)).*app.data.xaxis) +...
                vars(2)*exp(-1*(2*vars(3) + 3*wn_calced +...
                vars(4)*N).*app.data.xaxis);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function res = tri_func(app,vars)
            global N wn_calced
            
            ydata = vars(1)*exp(-1*(2*vars(4)).*app.data.xaxis) +...
                vars(2)*exp(-1*(2*vars(4) + 3*wn_calced +...
                vars(5)*N).*app.data.xaxis) +...
                vars(3)*exp(-1*(2*vars(4) + wn_calced +...
                vars(5)*N).*app.data.xaxis);
            
            res = sum((ydata - app.data.spc(:,app.data.index)).^2);
            
        end
        
        function calc_phase_correction(app)
            
            for l = 1:length(app.data.orig_spc(1,:))
                switch app.PhaseOptionDropDown.Value
                    case 'exp'
                        
                        phases = linspace(-pi, pi, 200);
                        spc2 = app.data.orig_spc(:,l).*exp(-1i*phases);
                        for k = 1:length(phases)
                            sum_sqrs(k) = sum(real(spc2(:,k)).^2) / sum(imag(spc2(:,k)).^2);
                        end
                        [~,b] = max(sum_sqrs);
                        
                        app.data.best_phase(l) = phases(b);
                        
                    case 'inv cos'
                        
                        app.data.best_phase(l) = acos(dot(app.data.real_spc(:,l),app.data.imag_spc(:,l))./...
                            (sqrt(sum(app.data.real_spc(:,l).^2)).*sqrt(sum(app.data.imag_spc(:,l).^2))));
                        
                    case 'arctan'
                        
                        app.data.best_phase(l) =...
                            atan(sum(app.data.imag_spc(:,l)) ./...
                            sum(app.data.real_spc(:,l)));
                        
                end
            end
            set(app.PhaseCorrectSlider,'Limits',...
                [app.data.best_phase(app.data.index)-...
                pi,app.data.best_phase(app.data.index)+pi],...
                'Value',app.data.best_phase(app.data.index))
            set(app.PhaseCorrectEditField,'Value',...
                app.data.best_phase(app.data.index))
        end
        
        function apply_phase_correction(app)
            set(app.PhaseangleEditField,'Value',app.data.best_phase(app.data.index)*180/pi)
            for l = 1:length(app.data.orig_spc(1,:))
                switch app.PhaseOptionDropDown.Value
                    
                    case 'inv cos'
                        
                        sf = sin(app.data.best_phase(l));
                        cf = 1/cos(app.data.best_phase(l));
                        
                        app.data.corr_imag_spc(:,l) = app.data.imag_spc(:,l)*sf*cf;
                        app.data.spc(:,l) = (app.data.real_spc(:,l)  - app.data.imag_spc(:,l).*sf).*cf;
                        
                    otherwise
                        
                        spc2 = app.data.orig_spc(:,l).*exp(-1i*app.data.best_phase(l));
                        app.data.spc(:,l) = real(spc2);
                        app.data.corr_imag_spc(:,l) = imag(spc2);
                        
                end
            end
            app.data.corr_imag_spc = app.data.corr_imag_spc;%./max(app.data.corr_imag_spc);
            app.data.spc = app.data.spc;%./max(app.data.spc);
        end
        
        function slide_phase_correction(app,phase)
            set(app.PhaseangleEditField,'Value',phase*180/pi)
            switch app.PhaseOptionDropDown.Value
                
                case 'inv cos'
                    
                    sf = sin(phase);
                    cf = 1/cos(phase);
                    
                    app.data.corr_imag_spc(:,app.data.index) =...
                        app.data.imag_spc(:,app.data.index)*sf*cf;
                    app.data.spc(:,app.data.index) = ...
                        (app.data.real_spc(:,app.data.index) -...
                        app.data.imag_spc(:,app.data.index).*sf).*cf;
                    
                otherwise
                    
                    spc2 = app.data.orig_spc(:,app.data.index).*...
                        exp(-1i*phase);
                    app.data.spc(:,app.data.index) = real(spc2);
                    app.data.corr_imag_spc(:,app.data.index) = imag(spc2);
                    
            end
        end
        
        function res = inv_recovery(app,vars)
            
            ydata = vars(1).*(1 - 2.*exp((-1.*app.data.xaxis)./vars(2)));
            
            res = sum((ydata - app.data.spc_2_fit).^2);
            
        end
        
        function res = sat_recovery(app,vars)
            
            ydata = vars(1).*(1 - exp((-1.*app.data.xaxis)./vars(2)));
            
            res = sum((ydata - app.data.spc_2_fit).^2);
            
        end
        
        function res = spin_spin_relax(app,vars)
            global p
            ydata = vars(1).*exp(-2.*((app.data.xaxis./vars(2)).^p));
            
            res = sum((ydata - app.data.spc_2_fit).^2);
            
        end
        
        function plot_complex(app)
            
            switch get(app.ShowImaginaryButton,'Value')
                case 1
                    plot(app.UIAxes, app.data.xaxis, app.data.imag_spc(:,app.data.index),'linewidth',1.5);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, app.data.xaxis, app.data.corr_imag_spc(:,app.data.index),'linewidth',1.5);
                    legend(app.UIAxes,'Original Imaginary','Phased Imaginary','location','southeast')
                case 0
                    plot(app.UIAxes, app.data.xaxis, app.data.real_spc(:,app.data.index),'linewidth',1.5);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
                    legend(app.UIAxes,'Original','Phased','location','southeast')
            end
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            
        end
        function plot_sliding(app,vars)
            
            exp_order = app.STPRButtonGroup.SelectedObject;
            
            switch exp_order.Text
                case 'Bi-'
                    ydata = vars.Ai*exp(-1*(2*vars.we).*app.data.xaxis) +...
                        vars.Bi*exp(-1*(2*vars.we + 3*vars.wn +...
                        vars.Kx*vars.N).*app.data.xaxis);
                case 'Tri-'
                    ydata = vars.Ai*exp(-1*(2*vars.we).*app.data.xaxis) +...
                        vars.Bi*exp(-1*(2*vars.we + 3*vars.wn +...
                        vars.Kx*vars.N).*app.data.xaxis) +...
                        vars.Ci*exp(-1*(2*vars.we + vars.wn +...
                        vars.Kx*vars.N).*app.data.xaxis);
            end
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, ydata,'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function load_data(app, event)
            
            app.exponFitter.Visible = 'Off';
            try
                [name,path]=uigetfile({'*.*';'*.DTA';'*.spc';'*.ESR';...
                    '*.txt';'*.dat';'*.xml'});
                [~,exten]=strtok(name,'.');
                set(app.Label,'Text',name)
            catch
                app.exponFitter.Visible = 'On';
                return
            end
            app.exponFitter.Visible = 'On';
            
            app.data = [];
            app.expfit = [];
            app.tags = [];
            
            set(app.SliceSpinner,'Value',1)
            app.data.index = 1;
            app.tags.fit_exists = 'n';
            app.data_exists = 'y';
            
            [app.data.xaxis,app.data.orig_spc,app.data.params,app.data.scnD] =...
                opendata(app,strcat(path,filesep,name),exten);
            app.data.loaded_spc = app.data.orig_spc;
            app.data.loaded_xaxis = app.data.xaxis;
            
            if ~isreal(app.data.orig_spc)
                app.data.real_spc = [];
                app.data.real_spc = real(app.data.orig_spc);
                app.data.spc = app.data.real_spc;
                app.data.imag_spc = [];
                app.data.imag_spc = imag(app.data.orig_spc);
                app.tags.is_complex = 'y';
                
                app.data.loaded_real_spc = app.data.real_spc;
                app.data.loaded_imag_spc = app.data.imag_spc;
                
                app.calc_phase_correction()
                app.phase_corrected(app)
                app.plot_complex()
                
            else
                app.data.spc = app.data.orig_spc;
                app.tags.is_complex = 'n';
                app.spin_index(app)
            end
            
            
            if length(app.data.orig_spc(1,:))>1
                set(app.SliceSpinner,'Visible','on')
                set(app.SliceSpinner,'Limits',[1,length(app.data.orig_spc(1,:))])
                set(app.ofLabel,'Visible','on','Text',['of ',...
                    num2str(length(app.data.orig_spc(1,:)))])
                set(app.SliceLabel,'Visible','on')
            else
                set(app.SliceSpinner,'Visible','off')
                set(app.ofLabel,'Visible','off')
                set(app.SliceLabel,'Visible','off')
            end
            
            set(app.LeftshiftSpinner,'Limits',[0,length(app.data.orig_spc(:,1))],'Value',0)
            
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'trend')
            hold(app.UIAxes,'off')
            app.reset_pars(app)
            if isfield(app.data.params, 'XUNI')
                set(app.UnitsLabel,'Text',['Units: ',app.data.params.XUNI])
                xlabel(app.UIAxes,app.data.params.XUNI)
            else
                set(app.UnitsLabel,'Text','Units: not found')
                xlabel(app.UIAxes,'not found')
            end
            
            set(app.ShowImaginaryButton,'Value',0)
            set(app.SmoothingSpinner,'Value',0)
            set(app.LeftshiftSpinner,'Value',0)
            
        end

        % Value changed function: SliceSpinner
        function spin_index(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            app.data.index = app.SliceSpinner.Value;
            
            switch app.tags.is_complex
                case 'y'
                    set(app.PhaseCorrectSlider,'Limits',...
                        [app.data.best_phase(app.data.index)-...
                        pi,app.data.best_phase(app.data.index)+pi],...
                        'Value',app.data.best_phase(app.data.index))
                    set(app.PhaseCorrectEditField,'Value',...
                        app.data.best_phase(app.data.index))
                    app.phase_corrected(app)
                    switch get(app.ShowImaginaryButton,'Value')
                        case 1
                            plot(app.UIAxes, app.data.xaxis, app.data.corr_imag_spc(:,app.data.index),'linewidth',1.5);
                        case 0
                            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
                    end
                    xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
                case 'n'
                    plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            end
            
            switch app.tags.fit_exists
                case 'n'
                    legend(app.UIAxes,'Data','location','southeast')
                otherwise
                    
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, app.data.xaxis, app.data.fit_spc(:,app.data.index),'linewidth',2);
                    legend(app.UIAxes,'Data','Fit')
                    
                    switch app.tags.fit_exists
                        case 'exp'
                            exp_order = app.ExponentialButtonGroup.SelectedObject;
                            
                            switch exp_order.Text
                                case 'Mono-'
                                    set(app.offsetEditField,'Value',app.expfit.offset(app.data.index))
                                    set(app.tau1EditField,'Value',app.expfit.tau(app.data.index))
                                    set(app.Coeff1EditField,'Value',app.expfit.coeffs(app.data.index))
                                    set(app.tau2EditField,'Value',0)
                                    set(app.Coeff2EditField,'Value',0)
                                case 'Bi-'
                                    set(app.offsetEditField,'Value',app.expfit.offset(app.data.index))
                                    set(app.tau1EditField,'Value',app.expfit.tau(app.data.index,1))
                                    set(app.Coeff1EditField,'Value',app.expfit.coeffs(app.data.index,1))
                                    set(app.tau2EditField,'Value',app.expfit.tau(app.data.index,2))
                                    set(app.Coeff2EditField,'Value',app.expfit.coeffs(app.data.index,2))
                            end
                        case 'inv'
                            set(app.InvRecovEdit,'Value',app.data.T1(app.data.index))
                        case 'sat'
                            set(app.SatRecovEdit,'Value',app.data.T1(app.data.index))
                        case 'spin'
                            set(app.SpinSpinEdit,'Value',app.data.T2(app.data.index))
                    end
            end
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            
            
        end

        % Value changing function: PhaseCorrectSlider
        function phase_correcting(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            changingValue = event.Value;
            
            set(app.PhaseCorrectEditField,'Value',changingValue)
            app.slide_phase_correction(changingValue)
            
            switch get(app.ShowImaginaryButton,'Value')
                case 1
                    plot(app.UIAxes, app.data.xaxis, app.data.imag_spc(:,app.data.index),'linewidth',1.5);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, app.data.xaxis, app.data.corr_imag_spc(:,app.data.index),'linewidth',1.5);
                    legend(app.UIAxes,'Original Imaginary','Phased Imaginary','location','southeast')
                case 0
                    plot(app.UIAxes, app.data.xaxis, app.data.real_spc(:,app.data.index),'linewidth',1.5);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
                    legend(app.UIAxes,'Original','Phased','location','southeast')
            end
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            
            
        end

        % Value changed function: PhaseCorrectSlider
        function phase_correcting_done(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            slid_to_phase = app.PhaseCorrectSlider.Value;
            ratio = slid_to_phase/app.data.best_phase(app.data.index);
            app.data.best_phase = app.data.best_phase.*ratio;
            
            app.phase_corrected(app)
            app.plot_complex()
            
        end

        % Callback function
        function phase_corrected(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            
            app.apply_phase_correction()
            
        end

        % Callback function: PhaseOptionDropDown, ResetPhaseButton
        function phase_correct_reset(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            
            app.calc_phase_correction()
            app.phase_corrected(app)
            app.plot_complex()
            
            
        end

        % Button pushed function: Rotateby180Button
        function rotate_180(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            
            app.data.best_phase = app.data.best_phase + pi;
            set(app.PhaseCorrectSlider,'Limits',...
                [app.data.best_phase(app.data.index)-pi,app.data.best_phase(app.data.index)+pi],...
                'Value',app.data.best_phase(app.data.index))
            set(app.PhaseCorrectEditField,'Value',app.data.best_phase(app.data.index))
            
            app.phase_corrected(app)
            app.plot_complex()
            
        end

        % Button pushed function: Rotateby90Button
        function rotate_90(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            
            app.data.best_phase = app.data.best_phase + pi/2;
            set(app.PhaseCorrectSlider,'Limits',...
                [app.data.best_phase(app.data.index)-pi,app.data.best_phase(app.data.index)+pi],...
                'Value',app.data.best_phase(app.data.index))
            set(app.PhaseCorrectEditField,'Value',app.data.best_phase(app.data.index))
            
            app.phase_corrected(app)
            app.plot_complex()
            
        end

        % Button pushed function: SwitchReImButton
        function switch_reim(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            spci = app.data.imag_spc;
            spcr = app.data.real_spc;
            
            app.data.real_spc = spci;
            app.data.imag_spc = spcr;
            
            app.calc_phase_correction();
            
            app.phase_corrected(app)
            app.plot_complex()
            
        end

        % Callback function: DetectButtonGroup, FitOpt_Dropdown, 
        % FitSTPRELDORButton, PumpButtonGroup, RefitButton, 
        % STPRButtonGroup
        function fit_bennati_model(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            
            coeff = [];
            app.tags.fit_exists = 'n';
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'trend')
            
            Ai = app.AiEditField.Value;
            Bi = app.BiEditField.Value;
            Ci = app.CiEditField.Value;
            we = app.weEditField.Value;
            wn = app.wnEditField.Value;
            Kx = app.KxEditField.Value;
            global N wn_calced
            N = app.NEditField.Value / 1e6;
            wn_calced = wn;
            
            exp_order = app.STPRButtonGroup.SelectedObject;
            
            switch app.FitOpt_Dropdown.Value
                case 'fit'
                    try
                        set(app.OptimizingLabel,'Visible','on','Text','Fitting...')
                        drawnow()
                        
                        switch exp_order.Text
                            case 'Bi-'
                                if app.taupsEditField.Value ~=0
                                    start_pts = [Ai Bi we Kx];
                                    lb = start_pts - abs(start_pts.*1000);
                                    ub = start_pts + abs(start_pts.*1000);
                                    bi_exp_fun = @(Ai,Bi,we,Kx,x)Ai*exp(-2*we*x) +...
                                        Bi*exp(-1*(2*we + 3*wn_calced + Kx*N)*x);
                                    [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,app.data.index),bi_exp_fun,...
                                        'StartPoint',start_pts,...
                                        'Lower',lb,...
                                        'Upper',ub);
                                    app.data.model_spc = bi_exp_fun(coeff.Ai,coeff.Bi,...
                                        coeff.we,coeff.Kx,app.data.xaxis);
                                else
                                    start_pts = [Ai Bi we wn Kx];
                                    lb = start_pts - abs(start_pts.*1000);
                                    ub = start_pts + abs(start_pts.*1000);
                                    bi_exp_fun = @(Ai,Bi,we,wn,Kx,x)Ai*exp(-2*we*x) +...
                                        Bi*exp(-1*(2*we + 3*wn + Kx*N)*x);
                                    [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,app.data.index),bi_exp_fun,...
                                        'StartPoint',start_pts,...
                                        'Lower',lb,...
                                        'Upper',ub);
                                    app.data.model_spc = bi_exp_fun(coeff.Ai,coeff.Bi,...
                                        coeff.we,coeff.wn,coeff.Kx,app.data.xaxis);
                                    set(app.wnEditField,'Value',coeff.wn)
                                end
                                set(app.AiEditField,'Value',coeff.Ai)
                                set(app.BiEditField,'Value',coeff.Bi)
                                set(app.weEditField,'Value',coeff.we)
                                set(app.KxEditField,'Value',coeff.Kx)
                                
                            case 'Tri-'
                                if app.taupsEditField.Value ~=0
                                    start_pts = [Ai Bi Ci we Kx];
                                    lb = start_pts - abs(start_pts.*1000);
                                    ub = start_pts + abs(start_pts.*1000);
                                    tri_exp_fun = @(Ai,Bi,Ci,we,Kx,x)Ai*exp(-2*we*x) +...
                                        Bi*exp(-1*(2*we + 3*wn_calced + Kx*N)*x) + Ci*exp(-1*(2*we +...
                                        wn_calced + Kx*N)*x);
                                    [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,app.data.index),tri_exp_fun,...
                                        'StartPoint',start_pts,...
                                        'Lower',lb,...
                                        'Upper',ub);
                                    app.data.model_spc = tri_exp_fun(coeff.Ai,coeff.Bi,coeff.Ci,...
                                        coeff.we,coeff.Kx,app.data.xaxis);
                                else
                                    start_pts = [Ai Bi Ci we wn Kx];
                                    lb = start_pts - abs(start_pts.*1000);
                                    ub = start_pts + abs(start_pts.*1000);
                                    tri_exp_fun = @(Ai,Bi,Ci,we,wn,Kx,x)Ai*exp(-2*we*x) +...
                                        Bi*exp(-1*(2*we + 3*wn + Kx*N)*x) + Ci*exp(-1*(2*we +...
                                        wn + Kx*N)*x);
                                    [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,app.data.index),tri_exp_fun,...
                                        'StartPoint',start_pts,...
                                        'Lower',lb,...
                                        'Upper',ub);
                                    app.data.model_spc = tri_exp_fun(coeff.Ai,coeff.Bi,coeff.Ci,...
                                        coeff.we,coeff.wn,coeff.Kx,app.data.xaxis);
                                    set(app.wnEditField,'Value',coeff.wn)
                                end
                                set(app.AiEditField,'Value',coeff.Ai)
                                set(app.BiEditField,'Value',coeff.Bi)
                                set(app.CiEditField,'Value',coeff.Ci)
                                set(app.weEditField,'Value',coeff.we)
                                set(app.KxEditField,'Value',coeff.Kx)
                        end
                    catch
                        set(app.OptimizingLabel,'Visible','on','Text','Try a different fit type')
                    end
                case 'lsqcurvefit'
                    set(app.OptimizingLabel,'Visible','on','Text','Fitting...')
                    drawnow()
                    fun_evals = get(app.FunEvalsEditField,'Value');
                    options = optimset('Algorithm','levenberg-marquardt','Display','off','MaxIter',fun_evals,'MaxFunEvals',fun_evals,'TolFun',1e-10,'TolX',1e-10);
                    
                    switch exp_order.Text
                        case 'Bi-'
                            if app.taupsEditField.Value ~=0
                                start_pts = [Ai Bi we Kx];
                                lb = start_pts - abs(start_pts.*1000);
                                ub = start_pts + abs(start_pts.*1000);
                                bi_exp_fun = @(vars,x)vars(1)*exp(-1*(2*vars(3)).*x) +...
                                    vars(2)*exp(-1*(2*vars(3) + 3*wn_calced +...
                                    vars(4)*N).*x);
                                [coeff,~,~] = lsqcurvefit(bi_exp_fun,start_pts,app.data.xaxis,app.data.spc(:,app.data.index),lb,ub,options);
                                
                                app.data.model_spc = bi_exp_fun(coeff,app.data.xaxis);
                                
                                set(app.KxEditField,'Value',coeff(4))
                            else
                                start_pts = [Ai Bi we wn Kx];
                                lb = start_pts - abs(start_pts.*1000);
                                ub = start_pts + abs(start_pts.*1000);
                                bi_exp_fun = @(vars,x)vars(1)*exp(-1*(2*vars(3)).*x) +...
                                    vars(2)*exp(-1*(2*vars(3) + 3*vars(4) +...
                                    vars(5)*N).*x);
                                [coeff,~,~] = lsqcurvefit(bi_exp_fun,start_pts,app.data.xaxis,app.data.spc(:,app.data.index),lb,ub,options);
                                
                                app.data.model_spc = bi_exp_fun(coeff,app.data.xaxis);
                                
                                set(app.wnEditField,'Value',coeff(4))
                                set(app.KxEditField,'Value',coeff(5))
                            end
                            set(app.AiEditField,'Value',coeff(1))
                            set(app.BiEditField,'Value',coeff(2))
                            set(app.weEditField,'Value',coeff(3))
                            
                        case 'Tri-'
                            if app.taupsEditField.Value ~=0
                                start_pts = [Ai Bi Ci we Kx];
                                lb = start_pts - abs(start_pts.*1000);
                                ub = start_pts + abs(start_pts.*1000);
                                tri_exp_fun = @(vars,x)vars(1)*exp(-1*(2*vars(4)).*x) +...
                                    vars(2)*exp(-1*(2*vars(4) + 3*wn_calced +...
                                    vars(5)*N).*x) +...
                                    vars(3)*exp(-1*(2*vars(4) + wn_calced +...
                                    vars(5)*N).*x);
                                [coeff,~,~] = lsqcurvefit(tri_exp_fun,start_pts,app.data.xaxis,app.data.spc(:,app.data.index),lb,ub,options);
                                
                                app.data.model_spc = tri_exp_fun(coeff,app.data.xaxis);
                                
                                set(app.KxEditField,'Value',coeff(5))
                            else
                                start_pts = [Ai Bi Ci we wn Kx];
                                lb = start_pts - abs(start_pts.*1000);
                                ub = start_pts + abs(start_pts.*1000);
                                tri_exp_fun = @(vars,x)vars(1)*exp(-1*(2*vars(4)).*x) +...
                                    vars(2)*exp(-1*(2*vars(4) + 3*vars(5) +...
                                    vars(6)*N).*x) +...
                                    vars(3)*exp(-1*(2*vars(4) + vars(5) +...
                                    vars(6)*N).*x);
                                [coeff,~,~] = lsqcurvefit(tri_exp_fun,start_pts,app.data.xaxis,app.data.spc(:,app.data.index),lb,ub,options);
                                
                                app.data.model_spc = tri_exp_fun(coeff,app.data.xaxis);
                                
                                set(app.wnEditField,'Value',coeff(5))
                                set(app.KxEditField,'Value',coeff(6))
                            end
                            set(app.AiEditField,'Value',coeff(1))
                            set(app.BiEditField,'Value',coeff(2))
                            set(app.CiEditField,'Value',coeff(3))
                            set(app.weEditField,'Value',coeff(4))
                    end
                    
                case 'Optimize'
                    
                    set(app.OptimizingLabel,'Visible','on','Text','Optimizing...')
                    drawnow()
                    fun_evals = get(app.FunEvalsEditField,'Value');
                    options = optimset('Display','off','MaxIter',fun_evals,'MaxFunEvals',fun_evals,'TolFun',1e-10,'TolX',1e-10);
                    
                    switch exp_order.Text
                        case 'Bi-'
                            if app.taupsEditField.Value ~=0
                                start_pts = [Ai Bi we Kx];
                                coeff = fminsearch(@app.bi_func,start_pts,options);
                                app.data.model_spc = coeff(1)*exp(-1*(2*coeff(3)).*...
                                    app.data.xaxis) + coeff(2)*exp(-1*(2*coeff(3) +...
                                    3*wn_calced + coeff(4)*N).*app.data.xaxis);
                                set(app.KxEditField,'Value',coeff(4))
                                set(app.KxSlider,'Limits',[.1*coeff(4) 10*coeff(4)],'Value',coeff(4))
                            else
                                start_pts = [Ai Bi we wn Kx];
                                coeff = fminsearch(@app.bi_func_wn,start_pts,options);
                                app.data.model_spc = coeff(1)*exp(-1*(2*coeff(3)).*...
                                    app.data.xaxis) + coeff(2)*exp(-1*(2*coeff(3) +...
                                    3*coeff(4) + coeff(5)*N).*app.data.xaxis);
                                set(app.wnEditField,'Value',coeff(4))
                                set(app.KxEditField,'Value',coeff(5))
                            end
                            set(app.AiEditField,'Value',coeff(1))
                            set(app.BiEditField,'Value',coeff(2))
                            set(app.weEditField,'Value',coeff(3))
                            
                        case 'Tri-'
                            if app.taupsEditField.Value ~=0
                                start_pts = [Ai Bi Ci we Kx];
                                coeff = fminsearch(@app.tri_func,start_pts,options);
                                app.data.model_spc = coeff(1)*exp(-1*(2*coeff(4)).*...
                                    app.data.xaxis) + coeff(2)*exp(-1*(2*coeff(4) +...
                                    3*wn_calced + coeff(5)*N).*app.data.xaxis) +...
                                    coeff(3)*exp(-1*(2*coeff(4) + wn_calced + coeff(5)*N).*app.data.xaxis);
                                set(app.KxEditField,'Value',coeff(5))
                            else
                                start_pts = [Ai Bi Ci we wn Kx];
                                coeff = fminsearch(@app.tri_func_wn,start_pts,options);
                                app.data.model_spc = coeff(1)*exp(-1*(2*coeff(4)).*...
                                    app.data.xaxis) + coeff(2)*exp(-1*(2*coeff(4) +...
                                    3*coeff(5) + coeff(6)*N).*app.data.xaxis) +...
                                    coeff(3)*exp(-1*(2*coeff(4) + coeff(5) + coeff(6)*N).*app.data.xaxis);
                                
                                set(app.wnEditField,'Value',coeff(5))
                                set(app.KxEditField,'Value',coeff(6))
                            end
                            set(app.AiEditField,'Value',coeff(1))
                            set(app.BiEditField,'Value',coeff(2))
                            set(app.CiEditField,'Value',coeff(3))
                            set(app.weEditField,'Value',coeff(4))
                    end
            end
            
            set(app.AiSlider,'Value',0)
            set(app.BiSlider,'Value',0)
            set(app.CiSlider,'Value',0)
            set(app.weSlider,'Value',0)
            set(app.wnSlider,'Value',0)
            set(app.KxSlider,'Value',0)
            
            we = app.weEditField.Value;
            wn = app.wnEditField.Value;
            Kx = app.KxEditField.Value;
            
            pump_s = app.PumpButtonGroup.SelectedObject;
            %detect_s = app.DetectButtonGroup.SelectedObject;
            
            switch pump_s.Text
                case 's1'
                    
                    s1 = 1;
                    
                    s2 = 1-...
                        (1/...
                        (1+...
                        ((wn+((1/3)*Kx*N)+((1/(6*we))*(3*wn+Kx*N)*(wn+Kx*N)))/...
                        (2*we+2*wn+Kx*N))));
                    
                    s3 = 1-...
                        (1/...
                        (1+...
                        ((((1/3)*Kx*N)+((1/(6*we))*(3*wn+Kx*N)*(wn+Kx*N)))/...
                        (2*we+3*wn+Kx*N))));
                    
                case 's2'
                    
                    s1 = 1-(1/(1+((1/(6*we))*(3*wn + Kx*N))));
                    s2 = 1;
                    s3 = 1-(1/(1+((1/(6*we))*(3*wn + Kx*N))));
                    
                case 's3'
                    
                    s1 = 1-...
                        (1/...
                        (1+...
                        ((((1/3)*Kx*N)+((1/(6*we))*(3*wn+Kx*N)*(wn+Kx*N)))/...
                        (2*we+3*wn+Kx*N))));
                    
                    s2 = 1-...
                        (1/...
                        (1+...
                        ((wn+((1/3)*Kx*N)+((1/(6*we))*(3*wn+Kx*N)*(wn+Kx*N)))/...
                        (2*we+2*wn+Kx*N))));
                    
                    s3 = 1;
                    
            end
            
            set(app.STPRListBox,'Items',{...
                ['T1e = ', num2str(1/(2*we))],...
                ['T1n = ', num2str(1/(2*wn))],...
                ['s1 = ', num2str(s1)],...
                ['s2 = ', num2str(s2)],...
                ['s3 = ', num2str(s3)],...
                ['s_eff = ', num2str((s1 + s2 + s3) / 3)]})
            
            set(app.OptimizingLabel,'Text','Done.')
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.model_spc,'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
            
            set(app.ShowImaginaryButton,'Value',0)
            
        end

        % Value changed function: k1nsEditField, taupsEditField
        function calc_wn(app, event)
            if app.k1nsEditField.Value~=0 && app.taupsEditField.Value ~=0
                tc = app.taupsEditField.Value/1e3;
                k = app.k1nsEditField.Value;
                w_n = ((2.6752218744e-1)*app.fieldmTEditField.Value/1000)/(2*pi); %ns
                w_e = ((1.76085963023e2)*app.fieldmTEditField.Value/1000)/(2*pi); %ns
                
                T1n = 1 / (k*(((7*tc) / (1 + (w_e.^2)*(tc.^2)))+((3*tc) /...
                    (1 + (w_n.^2)*(tc.^2)))));
                
                wn = (1/T1n)/2;
                
                set(app.wnEditField, 'Value', wn)
                
                set(app.STPRListBox,'Items',{...
                    ['T1n = ', num2str(T1n)]...
                    })
            else
                return
            end
            
            
        end

        % Button pushed function: ResetButton, ResetButton_2
        function reset_pars(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            set(app.AiEditField,'Value',max(app.data.spc(:,app.data.index)))
            set(app.BiEditField,'Value',max(app.data.spc(:,app.data.index))/10)
            set(app.CiEditField,'Value',max(app.data.spc(:,app.data.index))/100)
            set(app.weEditField,'Value',1/400)
            set(app.wnEditField,'Value',1/1.7e3)
            set(app.KxEditField,'Value',5.1)
            set(app.NEditField,'Value',0.005*1e6)
            set(app.STPRListBox,'Items',{})
            
        end

        % Button pushed function: FitButton
        function fit_exponential(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            app.data.fit_spc = [];
            app.expfit.tau = [];
            app.expfit.coeffs = [];
            app.expfit.offset = [];
            options = optimset('Display','off','MaxIter',5000,'MaxFunEvals',5000,'TolFun',1e-10,'TolX',1e-10);
            exp_order = app.ExponentialButtonGroup.SelectedObject;
            orig_index = app.data.index;
            switch exp_order.Text
                case 'Mono-'
                    switch app.expFitDropDown.Value
                        case 'fminsearch'
                            set(app.OptimizingLabel_2,'Visible','on','Text','Fitting...')
                            drawnow()
                            start_pts = [app.Coeff1EditField.Value...
                                app.tau1EditField.Value...
                                app.offsetEditField.Value];
                            for k = 1:length(app.data.spc(1,:))
                                app.data.index = k;
                                best_pts = fminsearch(@app.mono_exp_func,start_pts,options);
                                app.expfit.coeffs(k) = best_pts(1);
                                app.expfit.tau(k) = best_pts(2);
                                app.expfit.offset(k) = best_pts(3);
                                app.data.fit_spc(:,k) = (best_pts(1)*exp(-1*app.data.xaxis./best_pts(2))) + best_pts(3);
                            end
                            app.data.index = orig_index;
                        case 'easyspin'
                            [t,c,app.data.fit_spc] = exponfit(app.data.xaxis,app.data.spc,1);
                            app.expfit.tau = t.^(-1);
                            app.expfit.offset = c(:,1);
                            app.expfit.coeffs = c(:,2);
                    end
                    set(app.tau1EditField,'Value',app.expfit.tau(app.data.index))
                    set(app.Coeff1EditField,'Value',app.expfit.coeffs(app.data.index))
                    set(app.offsetEditField,'Value',app.expfit.offset(app.data.index))
                    set(app.tau2EditField,'Value',0)
                    set(app.Coeff2EditField,'Value',0)
                case 'Bi-'
                    switch app.expFitDropDown.Value
                        case 'fminsearch'
                            set(app.OptimizingLabel_2,'Visible','on','Text','Fitting...')
                            drawnow()
                            start_pts = [app.Coeff1EditField.Value...
                                app.tau1EditField.Value...
                                app.Coeff2EditField.Value...
                                app.tau2EditField.Value...
                                app.offsetEditField.Value];
                            for k = 1:length(app.data.spc(1,:))
                                app.data.index = k;
                                best_pts = fminsearch(@app.bi_exp_func,start_pts,options);
                                app.expfit.tau(k,1) = best_pts(2);
                                app.expfit.tau(k,2) = best_pts(4);
                                app.expfit.coeffs(k,1) = best_pts(1);
                                app.expfit.coeffs(k,2) = best_pts(3);
                                app.expfit.offset(k) = best_pts(5);
                                app.data.fit_spc(:,k) = (best_pts(1)*exp(-1*app.data.xaxis./best_pts(2))) +...
                                    (best_pts(3)*exp(-1*app.data.xaxis./best_pts(4))) + best_pts(5);
                            end
                            app.data.index = orig_index;
                        case 'easyspin'
                            [t,c,app.data.fit_spc] = exponfit(app.data.xaxis,app.data.spc,2);
                            app.expfit.tau = t.^(-1);
                            app.expfit.offset = c(:,1);
                            app.expfit.coeffs(:,1) = c(:,2);
                            app.expfit.coeffs(:,2) = c(:,3);
                    end
                    set(app.offsetEditField,'Value',app.expfit.offset(app.data.index))
                    set(app.tau1EditField,'Value',app.expfit.tau(app.data.index,1))
                    set(app.Coeff1EditField,'Value',app.expfit.coeffs(app.data.index,1))
                    set(app.tau2EditField,'Value',app.expfit.tau(app.data.index,2))
                    set(app.Coeff2EditField,'Value',app.expfit.coeffs(app.data.index,2))
            end
            
            if ~isempty(app.data.scnD)
                plot(app.UIAxes2,app.data.scnD,app.expfit.tau(:,1),'linewidth',1.5)
                ylabel(app.UIAxes2,'tau')
            end
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.fit_spc(:,app.data.index),'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit')
            set(app.OptimizingLabel_2,'Text','Done.')
            
            app.tags.fit_exists = 'exp';
            
            set(app.ShowImaginaryButton,'Value',0)
            
        end

        % Button pushed function: PlotButton
        function plot_exponential(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            offset = get(app.offsetEditField,'Value');
            
            c1 = get(app.Coeff1EditField,'Value');
            tau1 = get(app.tau1EditField,'Value');
            
            c2 = get(app.Coeff2EditField,'Value');
            tau2 = get(app.tau2EditField,'Value');
            
            ydata = offset + (c1 * exp(-1*app.data.xaxis./tau1)) + (c2 * exp(-1*app.data.xaxis./tau2));
            
            app.tags.fit_exists = 'y';
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, ydata,'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Exponential')
            
            
        end

        % Button pushed function: InversionRecoveryButton
        function inv_recov(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            set(app.InvFittingLabel,'Visible','on','Text','Fitting...')
            drawnow()
            
            coeff = [];
            app.data.T1 = [];
            app.data.M0 = [];
            options = optimset('Display','off','MaxIter',5000,'MaxFunEvals',5000,'TolFun',1e-10,'TolX',1e-10);
            start_pts = [max(app.data.spc(:,app.data.index)) 1000];
            fit_recov_fun = @(M0,T1,x)M0.*(1 - 2.*exp((-1.*x)./T1));
            lsq_recov_fun = @(vars,x)vars(1).*(1 - 2.*exp((-1.*x)./vars(2)));
            for k = 1:length(app.data.spc(1,:))
                switch app.RelaxDropDown.Value
                    case 'Optimize'
                        app.data.spc_2_fit = app.data.spc(:,k);
                        best = fminsearch(@app.inv_recovery,start_pts,options);
                        coeff.M0 = best(1);
                        coeff.T1 = best(2);
                    otherwise
                        lb = start_pts - abs(start_pts.*100);
                        ub = start_pts + abs(start_pts.*100);
                        switch app.RelaxDropDown.Value
                            case 'lsqcurvefit'
                                [best,~,~] = lsqcurvefit(lsq_recov_fun,start_pts,app.data.xaxis,app.data.spc(:,k),lb,ub,options);
                                coeff.M0 = best(1);
                                coeff.T1 = best(2);
                            case 'fit'
                                [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,k),fit_recov_fun,...
                                    'StartPoint',start_pts,...
                                    'Lower',lb,...
                                    'Upper',ub);
                        end
                end
                app.data.T1(k) = coeff.T1;
                app.data.M0(k) = coeff.M0;
                app.data.fit_spc(:,k) = fit_recov_fun(coeff.M0,coeff.T1,app.data.xaxis);
            end
            if length(app.data.spc(1,:))>1
                set(app.AverageInvT1EditField, 'Value', mean(app.data.T1));
                plot(app.UIAxes2,app.data.T1,'linewidth',1.5)
                ylabel(app.UIAxes2,'T1')
            end
            set(app.InvRecovEdit,'Value',app.data.T1(app.data.index))
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.fit_spc(:,app.data.index),'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
            
            set(app.InvFittingLabel,'Visible','on','Text','Done.')
            
            app.tags.fit_exists = 'inv';
            
        end

        % Button pushed function: SaturationRecoveryButton
        function sat_recov(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            set(app.SatFittingLabel,'Visible','on','Text','Fitting...')
            drawnow()
            
            coeff = [];
            app.data.T1 = [];
            app.data.M0 = [];
            options = optimset('Display','off','TolFun',1e-10,'TolX',1e-10);
            start_pts = [max(app.data.spc(:,app.data.index)) 1000];
            fit_recov_fun = @(M0,T1,x)M0.*(1 - exp((-1.*x)./T1));
            lsq_recov_fun = @(vars,x)vars(1).*(1 - exp((-1.*x)./vars(2)));
            for k = 1:length(app.data.spc(1,:))
                switch app.RelaxDropDown.Value
                    case 'Optimize'
                        app.data.spc_2_fit = app.data.spc(:,k);
                        best = fminsearch(@app.sat_recovery,start_pts,options);
                        coeff.M0 = best(1);
                        coeff.T1 = best(2);
                    otherwise
                        lb = start_pts - abs(start_pts.*100);
                        ub = start_pts + abs(start_pts.*100);
                        switch app.RelaxDropDown.Value
                            case 'lsqcurvefit'
                                [best,~,~] = lsqcurvefit(lsq_recov_fun,start_pts,app.data.xaxis,app.data.spc(:,k),lb,ub,options);
                                coeff.M0 = best(1);
                                coeff.T1 = best(2);
                            case 'fit'
                                [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,k),fit_recov_fun,...
                                    'StartPoint',start_pts,...
                                    'Lower',lb,...
                                    'Upper',ub);
                        end
                end
                app.data.T1(k) = coeff.T1;
                app.data.M0(k) = coeff.M0;
                app.data.fit_spc(:,k) = fit_recov_fun(coeff.M0,coeff.T1,app.data.xaxis);
            end
            if length(app.data.spc(1,:))>1
                set(app.AverageSatT1EditField, 'Value', mean(app.data.T1));
                plot(app.UIAxes2,app.data.T1,'linewidth',1.5)
                ylabel(app.UIAxes2,'T1')
            end
            set(app.SatRecovEdit,'Value',app.data.T1(app.data.index))
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.fit_spc(:,app.data.index),'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
            
            set(app.SatFittingLabel,'Visible','on','Text','Done.')
            
            app.tags.fit_exists = 'sat';
            
        end

        % Button pushed function: SpinSpinButton
        function spin_spin(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            set(app.SpinFittingLabel,'Visible','on','Text','Fitting...')
            drawnow()
            
            coeff = [];
            app.data.T2 = [];
            app.data.M0 = [];
            options = optimset('Display','off','TolFun',1e-10,'TolX',1e-10);
            start_pts = [max(app.data.spc(:,app.data.index)) 1000];
            global p
            p = get(app.T2expoEditField,'Value');
            fit_decay_fun = @(M0,T2,x)M0.*exp(-2.*((x./T2).^p));
            lsq_decay_fun = @(vars,x)vars(1).*exp(-2.*((x./vars(2)).^p));
            for k = 1: length(app.data.spc(1,:))
                switch app.RelaxDropDown.Value
                    case 'Optimize'
                        app.data.spc_2_fit = app.data.spc(:,k);
                        best = fminsearch(@app.spin_spin_relax,start_pts,options);
                        coeff.M0 = best(1);
                        coeff.T2 = best(2);
                    otherwise
                        lb = start_pts - abs(start_pts.*100);
                        ub = start_pts + abs(start_pts.*100);
                        switch app.RelaxDropDown.Value
                            case 'lsqcurvefit'
                                [best,~,~] = lsqcurvefit(lsq_decay_fun,start_pts,app.data.xaxis,app.data.spc(:,k),lb,ub,options);
                                coeff.M0 = best(1);
                                coeff.T2 = best(2);
                            case 'fit'
                                [coeff,~,~] = fit(app.data.xaxis,app.data.spc(:,k),fit_decay_fun,...
                                    'StartPoint',start_pts,...
                                    'Lower',lb,...
                                    'Upper',ub);
                        end
                end
                app.data.T2(k) = coeff.T2;
                app.data.M0(k) = coeff.M0;
                app.data.fit_spc(:,k) = fit_decay_fun(coeff.M0,coeff.T2,app.data.xaxis);
            end
            if length(app.data.spc(1,:))>1
                set(app.AverageT2EditField, 'Value', mean(app.data.T2));
                plot(app.UIAxes2,app.data.T2,'linewidth',1.5)
                ylabel(app.UIAxes2,'T2')
            end
            set(app.SpinSpinEdit,'Value',app.data.T2(app.data.index))
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.fit_spc(:,app.data.index),'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
            
            set(app.SpinFittingLabel,'Visible','on','Text','Done.')
            
            app.tags.fit_exists = 'spin';
            
        end

        % Value changed function: SmoothingSpinner
        function smooth_data(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            
            fsteps = app.SmoothingSpinner.Value;
            
            if fsteps~=0
                orig_spc = app.data.loaded_spc(...
                    app.LeftshiftSpinner.Value+1:end,app.data.index);
                spc = app.data.loaded_spc(...
                    app.LeftshiftSpinner.Value+1:end,app.data.index);
                orig_spc = datasmooth(orig_spc,fsteps);
                app.data.orig_spc = orig_spc;
                spc = datasmooth(spc,fsteps);
                app.data.spc = spc;
                switch app.tags.is_complex
                    case 'y'
                        real_spc = app.data.loaded_real_spc(...
                            app.LeftshiftSpinner.Value+1:end,app.data.index);
                        imag_spc = app.data.loaded_imag_spc(...
                            app.LeftshiftSpinner.Value+1:end,app.data.index);
                        real_spc = datasmooth(real_spc,fsteps);
                        imag_spc = datasmooth(imag_spc,fsteps);
                        app.data.real_spc = real_spc;
                        app.data.imag_spc = imag_spc;
                        app.phase_corrected(app)
                        app.plot_complex()
                    case 'n'
                        app.spin_index(app)
                end
                
            end
            
        end

        % Value changed function: LeftshiftSpinner
        function truncate_data(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            value = app.LeftshiftSpinner.Value;
            switch value
                case 0
                    app.data.orig_spc = [];
                    app.data.xaxis = [];
                    app.data.orig_spc = app.data.loaded_spc;
                    app.data.xaxis = app.data.loaded_xaxis;
                otherwise
                    for l = 1:length(app.data.spc(1,:))
                        temp(:,l) = app.data.loaded_spc(value+1:end,l);
                    end
                    app.data.orig_spc = [];
                    app.data.xaxis = [];
                    app.data.orig_spc = temp;
                    app.data.xaxis = app.data.loaded_xaxis(value+1:end);
            end
            app.data.model_spc = [];
            app.data.spc_2_fit = [];
            app.data.fit_spc = [];
            
            app.data.spc = [];
            app.data.spc = app.data.orig_spc;
            
            app.tags.fit_exists = 'n';
            
            if ~isreal(app.data.loaded_spc)
                app.data.real_spc = [];
                app.data.real_spc = real(app.data.orig_spc);
                app.data.imag_spc = [];
                app.data.imag_spc = imag(app.data.orig_spc);
                app.data.corr_imag_spc = [];
                
                app.calc_phase_correction()
                app.phase_corrected(app)
                app.plot_complex()
                
            else
                app.spin_index(app)
            end
            
            if app.SmoothingSpinner.Value~=0
                app.smooth_data(app)
            end
            
        end

        % Callback function: DimButton, PolyCorrectButton
        function poly_data(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            
            poly_order = get(app.PolyEditField,'Value');
            switch app.DimButton.Value
                case 0
                    set(app.DimButton,'Text','-')
                    dim = 1;
                case 1
                    if length(app.data.orig_spc(1,:))<2
                        set(app.DimButton,'Text','-','Value',0)
                        return
                    else
                        set(app.DimButton,'Text','|')
                        dim = 2;
                    end
            end
            
            [orig_spc,~] = basecorr(app.data.orig_spc,dim,poly_order);
            app.data.orig_spc = orig_spc;
            [spc,~] = basecorr(app.data.spc,dim,poly_order);
            app.data.spc = spc;
            
            switch app.tags.is_complex
                case 'y'
                    [real_spc,~] = basecorr(app.data.real_spc,dim,poly_order);
                    [imag_spc,~] = basecorr(app.data.imag_spc,dim,poly_order);
                    app.data.real_spc = real_spc;
                    app.data.imag_spc = imag_spc;
                    app.phase_corrected(app)
                    app.plot_complex()
                case 'n'
                    app.spin_index(app)
            end
            
            
        end

        % Button pushed function: RevertButton
        function revert_data(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            
            app.data.spc = [];
            app.data.spc = app.data.loaded_spc;
            app.data.orig_spc = [];
            app.data.orig_spc = app.data.loaded_spc;
            app.data.xaxis = [];
            app.data.xaxis = app.data.loaded_xaxis;
            
            app.data.index = app.SliceSpinner.Value;
            
            app.data.fit_spc = [];
            app.data.model_spc = [];
            app.tags.fit_exists = 'n';
            
            switch app.tags.is_complex
                case 'y'
                    app.data.corr_imag_spc = [];
                    app.data.real_spc = [];
                    app.data.imag_spc = [];
                    app.data.real_spc = app.data.loaded_real_spc;
                    app.data.imag_spc = app.data.loaded_imag_spc;
                    app.calc_phase_correction()
                    app.phase_corrected(app)
                    app.plot_complex()
                    
                case 'n'
                    app.spin_index(app)
            end
            
            set(app.SmoothingSpinner,'Value',0)
            set(app.LeftshiftSpinner,'Value',0)
            cla(app.UIAxes2)
            ylabel(app.UIAxes2,'trend')
            
        end

        % Value changed function: ShowImaginaryButton
        function toggle_realimag(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            switch app.tags.is_complex
                case 'n'
                    return
            end
            
            app.phase_corrected(app)
            app.plot_complex()
            
        end

        % Button pushed function: ToWorkspaceButton
        function addto_workspace(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            assignin('base','spc',app.data.spc)
            assignin('base','xaxis',app.data.xaxis)
            if app.data.model_spc
                assignin('base','model',app.data.model_spc)
            end
            if app.data.fit_spc
                assignin('base','fit',app.data.fit_spc)
            end
        end

        % Value changed function: AiSlider, BiSlider, CiSlider, 
        % KxSlider, weSlider, wnSlider
        function slid_stpr(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            N = app.NEditField.Value / 1e6;
            
            exp_order = app.STPRButtonGroup.SelectedObject;
            
            switch exp_order.Text
                case 'Bi-'
                    app.data.model_spc = Ai*exp(-1*(2*we).*app.data.xaxis) +...
                        Bi*exp(-1*(2*we + 3*wn +...
                        Kx*N).*app.data.xaxis);
                case 'Tri-'
                    app.data.model_spc = Ai*exp(-1*(2*we).*app.data.xaxis) +...
                        Bi*exp(-1*(2*we + 3*wn +...
                        Kx*N).*app.data.xaxis) +...
                        Ci*exp(-1*(2*we + wn +...
                        Kx*N).*app.data.xaxis);
            end
            
            plot(app.UIAxes, app.data.xaxis, app.data.spc(:,app.data.index),'linewidth',1.5);
            hold(app.UIAxes,'on')
            plot(app.UIAxes, app.data.xaxis, app.data.model_spc,'linewidth',2);
            xlim(app.UIAxes,[min(app.data.xaxis) max(app.data.xaxis)])
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Data','Fit','location','southeast')
            
        end

        % Value changing function: AiSlider
        function sliding_Ai(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*event.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Value changing function: BiSlider
        function sliding_Bi(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*event.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Value changing function: CiSlider
        function sliding_Ci(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*event.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Value changing function: weSlider
        function sliding_we(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*event.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Value changing function: wnSlider
        function sliding_wn(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*event.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Value changing function: KxSlider
        function sliding_Kx(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            vars.Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            vars.Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            vars.Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            vars.we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            vars.wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            vars.Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*event.Value);
            
            vars.N = app.NEditField.Value / 1e6;
            
            plot_sliding(app,vars)
            
        end

        % Button pushed function: CenterSlidersButton
        function keep_pars(app, event)
            switch app.data_exists
                case 'n'
                    return
            end
            
            Ai = app.AiEditField.Value +...
                (app.AiEditField.Value*app.AiSlider.Value);
            Bi = app.BiEditField.Value +...
                (app.BiEditField.Value*app.BiSlider.Value);
            Ci = app.CiEditField.Value +...
                (app.CiEditField.Value*app.CiSlider.Value);
            we = app.weEditField.Value +...
                (app.weEditField.Value*app.weSlider.Value);
            wn = app.wnEditField.Value +...
                (app.wnEditField.Value*app.wnSlider.Value);
            Kx = app.KxEditField.Value +...
                (app.KxEditField.Value*app.KxSlider.Value);
            
            set(app.AiEditField,'Value',Ai)
            set(app.AiSlider,'Value',0)
            set(app.BiEditField,'Value',Bi)
            set(app.BiSlider,'Value',0)
            set(app.CiEditField,'Value',Ci)
            set(app.CiSlider,'Value',0)
            set(app.weEditField,'Value',we)
            set(app.weSlider,'Value',0)
            set(app.wnEditField,'Value',wn)
            set(app.wnSlider,'Value',0)
            set(app.KxEditField,'Value',Kx)
            set(app.KxSlider,'Value',0)
            
        end

        % Callback function: GoButton, highEditField, lowEditField, 
        % middleEditField
        function calc_mhz(app, event)
            high_value = app.highEditField.Value/10;
            mid_value = app.middleEditField.Value/10;
            low_value = app.lowEditField.Value/10;
            
            low_mhz = mt2mhz(low_value - mid_value);
            low_mhz_3 = mt2mhz(low_value - high_value);
            mid_mhz_2 = mt2mhz(mid_value - low_value);
            mid_mhz_3 = mt2mhz(mid_value - high_value);
            high_mhz = mt2mhz(high_value - mid_value);
            high_mhz_2 = mt2mhz(high_value - low_value);
            
            set(app.lowLabel_3, 'Text', [num2str(low_mhz_3, '%2.2f'), ' MHz'])
            set(app.midLabel_2, 'Text', [num2str(mid_mhz_2, '%2.2f'), ' MHz'])
            set(app.midLabel_3, 'Text', [num2str(mid_mhz_3, '%2.2f'), ' MHz'])
            set(app.lowLabel, 'Text', [num2str(low_mhz, '%2.2f'), ' MHz'])
            set(app.highLabel, 'Text', [num2str(high_mhz, '%2.2f'), ' MHz'])
            set(app.highLabel_2, 'Text', [num2str(high_mhz_2, '%2.2f'), ' MHz'])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.exponFitter = uifigure('Visible', 'off');
            app.exponFitter.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.exponFitter);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, '')
            app.LoadButton = uibutton(app.exponFitter, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup = uitabgroup(app.exponFitter);
            app.STPRELDORTab = uitab(app.TabGroup);
            app.STPRELDORTab.Title = 'ST-PR-ELDOR';
            app.FitOpt_Dropdown = uidropdown(app.STPRELDORTab);
            app.FitOpt_Dropdown.Items = {'Optimize', 'lsqcurvefit', 'fit'};
            app.FitOpt_Dropdown.ValueChangedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.FitOpt_Dropdown.Value = 'Optimize';
            app.OptimizingLabel = uilabel(app.STPRELDORTab);
            app.OptimizingLabel.Visible = 'off';
            app.OptimizingLabel.Text = 'Optimizing....';
            app.FitSTPRELDORButton = uibutton(app.STPRELDORTab, 'push');
            app.FitSTPRELDORButton.ButtonPushedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.FitSTPRELDORButton.Text = 'Fit ST-PR-ELDOR';
            app.FunEvalsEditFieldLabel = uilabel(app.STPRELDORTab);
            app.FunEvalsEditFieldLabel.Text = 'FunEvals';
            app.FunEvalsEditField = uieditfield(app.STPRELDORTab, 'numeric');
            app.FunEvalsEditField.Value = 1000;
            app.STPRButtonGroup = uibuttongroup(app.STPRELDORTab);
            app.STPRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.STPRButtonGroup.Title = 'Exponential';
            app.STPRBi = uiradiobutton(app.STPRButtonGroup);
            app.STPRBi.Text = 'Bi-';
            app.STPRTri = uiradiobutton(app.STPRButtonGroup);
            app.STPRTri.Text = 'Tri-';
            app.STPRTri.Value = true;
            app.STPRListBox = uilistbox(app.STPRELDORTab);
            app.STPRListBox.Items = {};
            app.STPRListBox.Value = {};
            app.PumpButtonGroup = uibuttongroup(app.STPRELDORTab);
            app.PumpButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.PumpButtonGroup.Title = 'Pump';
            app.s1Button = uiradiobutton(app.PumpButtonGroup);
            app.s1Button.Text = 's1';
            app.s1Button.Value = true;
            app.s2Button = uiradiobutton(app.PumpButtonGroup);
            app.s2Button.Text = 's2';
            app.s3Button = uiradiobutton(app.PumpButtonGroup);
            app.s3Button.Text = 's3';
            app.DetectButtonGroup = uibuttongroup(app.STPRELDORTab);
            app.DetectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.DetectButtonGroup.Title = 'Detect';
            app.s1Button_2 = uiradiobutton(app.DetectButtonGroup);
            app.s1Button_2.Text = 's1';
            app.s1Button_2.Value = true;
            app.s2Button_2 = uiradiobutton(app.DetectButtonGroup);
            app.s2Button_2.Text = 's2';
            app.s3Button_2 = uiradiobutton(app.DetectButtonGroup);
            app.s3Button_2.Text = 's3';
            app.ResetButton_2 = uibutton(app.STPRELDORTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @reset_pars, true);
            app.ResetButton_2.Text = 'Reset';
            app.AdjustSTPRTab = uitab(app.TabGroup);
            app.AdjustSTPRTab.Title = 'Adjust ST-PR';
            app.AiSlider = uislider(app.AdjustSTPRTab);
            app.AiSlider.Limits = [-1 1];
            app.AiSlider.MajorTicks = [];
            app.AiSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.AiSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_Ai, true);
            app.AiSlider.MinorTicks = [];
            app.CiSlider = uislider(app.AdjustSTPRTab);
            app.CiSlider.Limits = [-1 1];
            app.CiSlider.MajorTicks = [];
            app.CiSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.CiSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_Ci, true);
            app.CiSlider.MinorTicks = [];
            app.BiSlider = uislider(app.AdjustSTPRTab);
            app.BiSlider.Limits = [-1 1];
            app.BiSlider.MajorTicks = [];
            app.BiSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.BiSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_Bi, true);
            app.BiSlider.MinorTicks = [];
            app.weSlider = uislider(app.AdjustSTPRTab);
            app.weSlider.Limits = [-1 1];
            app.weSlider.MajorTicks = [];
            app.weSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.weSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_we, true);
            app.weSlider.MinorTicks = [];
            app.KxSlider = uislider(app.AdjustSTPRTab);
            app.KxSlider.Limits = [-1 1];
            app.KxSlider.MajorTicks = [];
            app.KxSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.KxSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_Kx, true);
            app.KxSlider.MinorTicks = [];
            app.wnSlider = uislider(app.AdjustSTPRTab);
            app.wnSlider.Limits = [-1 1];
            app.wnSlider.MajorTicks = [];
            app.wnSlider.ValueChangedFcn = createCallbackFcn(app, @slid_stpr, true);
            app.wnSlider.ValueChangingFcn = createCallbackFcn(app, @sliding_wn, true);
            app.wnSlider.MinorTicks = [];
            app.weEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.weEditFieldLabel.Text = 'we';
            app.weEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.weEditField.ValueDisplayFormat = '%1.2e';
            app.wnEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.wnEditFieldLabel.Text = 'wn';
            app.wnEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.wnEditField.ValueDisplayFormat = '%1.2e';
            app.KxEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.KxEditFieldLabel.Text = 'Kx';
            app.KxEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.KxEditField.ValueDisplayFormat = '%1.2e';
            app.AiEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.AiEditFieldLabel.Text = 'Ai';
            app.AiEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.AiEditField.ValueDisplayFormat = '%1.2e';
            app.BiEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.BiEditFieldLabel.Text = 'Bi';
            app.BiEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.BiEditField.ValueDisplayFormat = '%1.2e';
            app.CiEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.CiEditFieldLabel.Text = 'Ci';
            app.CiEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.CiEditField.ValueDisplayFormat = '%1.2e';
            app.NEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.NEditField.ValueDisplayFormat = '%11.0f';
            app.k1nsEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.k1nsEditFieldLabel.Text = 'k (1/ns)';
            app.k1nsEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.k1nsEditField.ValueChangedFcn = createCallbackFcn(app, @calc_wn, true);
            app.k1nsEditField.Value = 0.00719;
            app.taupsEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.taupsEditFieldLabel.Text = 'tau (ps)';
            app.taupsEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.taupsEditField.ValueChangedFcn = createCallbackFcn(app, @calc_wn, true);
            app.CenterSlidersButton = uibutton(app.AdjustSTPRTab, 'push');
            app.CenterSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @keep_pars, true);
            app.CenterSlidersButton.Text = 'Center Sliders';
            app.RefitButton = uibutton(app.AdjustSTPRTab, 'push');
            app.RefitButton.ButtonPushedFcn = createCallbackFcn(app, @fit_bennati_model, true);
            app.RefitButton.Text = 'Re-fit';
            app.ResetButton = uibutton(app.AdjustSTPRTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset_pars, true);
            app.ResetButton.Text = 'Reset';
            app.fieldmTEditFieldLabel = uilabel(app.AdjustSTPRTab);
            app.fieldmTEditFieldLabel.Text = 'field (mT)';
            app.fieldmTEditField = uieditfield(app.AdjustSTPRTab, 'numeric');
            app.fieldmTEditField.Value = 330;
            app.PhaseTab = uitab(app.TabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseradLabel = uilabel(app.PhaseTab);
            app.PhaseradLabel.Text = 'Phase rad';
            app.PhaseCorrectSlider = uislider(app.PhaseTab);
            app.PhaseCorrectSlider.Limits = [0 2];
            app.PhaseCorrectSlider.ValueChangedFcn = createCallbackFcn(app, @phase_correcting_done, true);
            app.PhaseCorrectSlider.ValueChangingFcn = createCallbackFcn(app, @phase_correcting, true);
            app.PhaseCorrectSlider.Value = 1;
            app.PhaseangleEditFieldLabel = uilabel(app.PhaseTab);
            app.PhaseangleEditFieldLabel.Text = 'Phase angle';
            app.PhaseangleEditField = uieditfield(app.PhaseTab, 'numeric');
            app.PhaseCorrectEditField = uieditfield(app.PhaseTab, 'numeric');
            app.ResetPhaseButton = uibutton(app.PhaseTab, 'push');
            app.ResetPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @phase_correct_reset, true);
            app.ResetPhaseButton.Text = 'Reset';
            app.ShowImaginaryButton = uibutton(app.PhaseTab, 'state');
            app.ShowImaginaryButton.ValueChangedFcn = createCallbackFcn(app, @toggle_realimag, true);
            app.ShowImaginaryButton.Text = 'Show Imaginary';
            app.Rotateby180Button = uibutton(app.PhaseTab, 'push');
            app.Rotateby180Button.ButtonPushedFcn = createCallbackFcn(app, @rotate_180, true);
            app.Rotateby180Button.Text = 'Rotate by 180';
            app.Rotateby90Button = uibutton(app.PhaseTab, 'push');
            app.Rotateby90Button.ButtonPushedFcn = createCallbackFcn(app, @rotate_90, true);
            app.Rotateby90Button.Text = 'Rotate by 90';
            app.SwitchReImButton = uibutton(app.PhaseTab, 'push');
            app.SwitchReImButton.ButtonPushedFcn = createCallbackFcn(app, @switch_reim, true);
            app.SwitchReImButton.Text = 'Switch Re-Im';
            app.PhaseOptionDropDown = uidropdown(app.PhaseTab);
            app.PhaseOptionDropDown.Items = {'arctan', 'exp', 'inv cos'};
            app.PhaseOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @phase_correct_reset, true);
            app.PhaseOptionDropDown.Value = 'arctan';
            app.RelaxationsTab = uitab(app.TabGroup);
            app.RelaxationsTab.Title = 'Relaxations';
            app.InversionRecoveryButton = uibutton(app.RelaxationsTab, 'push');
            app.InversionRecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @inv_recov, true);
            app.InversionRecoveryButton.Text = 'Inversion Recovery';
            app.SpinSpinButton = uibutton(app.RelaxationsTab, 'push');
            app.SpinSpinButton.ButtonPushedFcn = createCallbackFcn(app, @spin_spin, true);
            app.SpinSpinButton.Text = 'Spin-Spin';
            app.InvRecovEdit = uieditfield(app.RelaxationsTab, 'numeric');
            app.SpinSpinEdit = uieditfield(app.RelaxationsTab, 'numeric');
            app.SaturationRecoveryButton = uibutton(app.RelaxationsTab, 'push');
            app.SaturationRecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @sat_recov, true);
            app.SaturationRecoveryButton.Text = 'Saturation Recovery';
            app.SatRecovEdit = uieditfield(app.RelaxationsTab, 'numeric');
            app.RelaxDropDown = uidropdown(app.RelaxationsTab);
            app.RelaxDropDown.Items = {'Optimize', 'lsqcurvefit', 'fit'};
            app.RelaxDropDown.Value = 'Optimize';
            app.InvFittingLabel = uilabel(app.RelaxationsTab);
            app.InvFittingLabel.Visible = 'off';
            app.InvFittingLabel.Text = 'Fitting...';
            app.SatFittingLabel = uilabel(app.RelaxationsTab);
            app.SatFittingLabel.Visible = 'off';
            app.SatFittingLabel.Text = 'Fitting...';
            app.SpinFittingLabel = uilabel(app.RelaxationsTab);
            app.SpinFittingLabel.Visible = 'off';
            app.SpinFittingLabel.Text = 'Fitting...';
            app.AverageT2EditFieldLabel = uilabel(app.RelaxationsTab);
            app.AverageT2EditFieldLabel.Text = 'Average T2';
            app.AverageT2EditField = uieditfield(app.RelaxationsTab, 'numeric');
            app.AverageT1EditFieldLabel = uilabel(app.RelaxationsTab);
            app.AverageT1EditFieldLabel.Text = 'Average T1';
            app.AverageSatT1EditField = uieditfield(app.RelaxationsTab, 'numeric');
            app.AverageT1EditField_2Label = uilabel(app.RelaxationsTab);
            app.AverageT1EditField_2Label.Text = 'Average T1';
            app.AverageInvT1EditField = uieditfield(app.RelaxationsTab, 'numeric');
            app.UnitsLabel = uilabel(app.RelaxationsTab);
            app.UnitsLabel.Text = 'Units: ';
            app.tT2EditFieldLabel = uilabel(app.RelaxationsTab);
            app.tT2EditFieldLabel.Text = '(-2t/T2)^';
            app.T2expoEditField = uieditfield(app.RelaxationsTab, 'numeric');
            app.T2expoEditField.Value = 1;
            app.DDataLabel = uilabel(app.RelaxationsTab);
            app.DDataLabel.Text = '2D Data:';
            app.ExponentialFitTab = uitab(app.TabGroup);
            app.ExponentialFitTab.Title = 'Exponential Fit';
            app.ExponentialButtonGroup = uibuttongroup(app.ExponentialFitTab);
            app.ExponentialButtonGroup.Title = 'Exponential';
            app.MonoButton = uiradiobutton(app.ExponentialButtonGroup);
            app.MonoButton.Text = 'Mono-';
            app.MonoButton.Value = true;
            app.BiButton = uiradiobutton(app.ExponentialButtonGroup);
            app.BiButton.Text = 'Bi-';
            app.FitButton = uibutton(app.ExponentialFitTab, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @fit_exponential, true);
            app.FitButton.Text = 'Fit';
            app.Coeff1EditFieldLabel = uilabel(app.ExponentialFitTab);
            app.Coeff1EditFieldLabel.Text = 'Coeff 1';
            app.Coeff1EditField = uieditfield(app.ExponentialFitTab, 'numeric');
            app.Coeff2EditFieldLabel = uilabel(app.ExponentialFitTab);
            app.Coeff2EditFieldLabel.Text = 'Coeff 2';
            app.Coeff2EditField = uieditfield(app.ExponentialFitTab, 'numeric');
            app.tau1Label = uilabel(app.ExponentialFitTab);
            app.tau1Label.Text = 'tau 1';
            app.tau1EditField = uieditfield(app.ExponentialFitTab, 'numeric');
            app.tau2Label = uilabel(app.ExponentialFitTab);
            app.tau2Label.Text = 'tau 2';
            app.tau2EditField = uieditfield(app.ExponentialFitTab, 'numeric');
            app.PlotButton = uibutton(app.ExponentialFitTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plot_exponential, true);
            app.PlotButton.Text = 'Plot';
            app.offsetEditFieldLabel = uilabel(app.ExponentialFitTab);
            app.offsetEditFieldLabel.Text = 'offset';
            app.offsetEditField = uieditfield(app.ExponentialFitTab, 'numeric');
            app.expFitDropDown = uidropdown(app.ExponentialFitTab);
            app.expFitDropDown.Items = {'fminsearch', 'easyspin'};
            app.expFitDropDown.Value = 'fminsearch';
            app.OptimizingLabel_2 = uilabel(app.ExponentialFitTab);
            app.OptimizingLabel_2.Visible = 'off';
            app.OptimizingLabel_2.Text = 'Optimizing....';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.PolyEditField = uieditfield(app.ToolsTab, 'numeric');
            app.PolyEditField.ValueDisplayFormat = '%1.0g';
            app.PolyEditField.Value = 2;
            app.DimButton = uibutton(app.ToolsTab, 'state');
            app.DimButton.ValueChangedFcn = createCallbackFcn(app, @poly_data, true);
            app.DimButton.Text = '-';
            app.DimLabel = uilabel(app.ToolsTab);
            app.DimLabel.Text = 'Dim';
            app.PolyCorrectButton = uibutton(app.ToolsTab, 'push');
            app.PolyCorrectButton.ButtonPushedFcn = createCallbackFcn(app, @poly_data, true);
            app.PolyCorrectButton.Text = 'Poly Correct';
            app.RevertButton = uibutton(app.ToolsTab, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @revert_data, true);
            app.RevertButton.Text = 'Revert';
            app.ToWorkspaceButton = uibutton(app.ToolsTab, 'push');
            app.ToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @addto_workspace, true);
            app.ToWorkspaceButton.Text = 'To Workspace';
            app.LeftshiftSpinnerLabel = uilabel(app.ToolsTab);
            app.LeftshiftSpinnerLabel.Text = 'Left shift';
            app.LeftshiftSpinner = uispinner(app.ToolsTab);
            app.LeftshiftSpinner.Limits = [0 Inf];
            app.LeftshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @truncate_data, true);
            app.SmoothingSpinnerLabel = uilabel(app.ToolsTab);
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.SmoothingSpinner = uispinner(app.ToolsTab);
            app.SmoothingSpinner.Limits = [0 Inf];
            app.SmoothingSpinner.ValueChangedFcn = createCallbackFcn(app, @smooth_data, true);
            app.FieldGLabel = uilabel(app.ToolsTab);
            app.FieldGLabel.Text = 'Field (G):';
            app.lowEditField = uieditfield(app.ToolsTab, 'numeric');
            app.lowEditField.ValueChangedFcn = createCallbackFcn(app, @calc_mhz, true);
            app.lowEditField.Value = 3375;
            app.middleEditField = uieditfield(app.ToolsTab, 'numeric');
            app.middleEditField.ValueChangedFcn = createCallbackFcn(app, @calc_mhz, true);
            app.middleEditField.Value = 3392;
            app.highEditField = uieditfield(app.ToolsTab, 'numeric');
            app.highEditField.ValueChangedFcn = createCallbackFcn(app, @calc_mhz, true);
            app.highEditField.Value = 3409;
            app.lowLabel = uilabel(app.ToolsTab);
            app.lowLabel.Text = 'MHz';
            app.highLabel = uilabel(app.ToolsTab);
            app.highLabel.Text = 'MHz';
            app.GoButton = uibutton(app.ToolsTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @calc_mhz, true);
            app.GoButton.Text = 'Go';
            app.OrderLabel = uilabel(app.ToolsTab);
            app.OrderLabel.Text = 'Order';
            app.midLabel = uilabel(app.ToolsTab);
            app.midLabel.Text = 'detect';
            app.highLabel_3 = uilabel(app.ToolsTab);
            app.highLabel_3.Text = 'detect';
            app.midLabel_3 = uilabel(app.ToolsTab);
            app.midLabel_3.Text = 'MHz';
            app.lowLabel_3 = uilabel(app.ToolsTab);
            app.lowLabel_3.Text = 'MHz';
            app.highLabel_2 = uilabel(app.ToolsTab);
            app.highLabel_2.Text = 'MHz';
            app.midLabel_2 = uilabel(app.ToolsTab);
            app.midLabel_2.Text = 'MHz';
            app.lowLabel_2 = uilabel(app.ToolsTab);
            app.lowLabel_2.Text = 'detect';
            app.SliceSpinner = uispinner(app.exponFitter);
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @spin_index, true);
            app.SliceSpinner.Value = 1;
            app.ofLabel = uilabel(app.exponFitter);
            app.ofLabel.Text = 'of ';
            app.UIAxes2 = uiaxes(app.exponFitter);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'indirect dim')
            ylabel(app.UIAxes2, 'trend')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.exponFitter);
            app.v02Label = uilabel(app.exponFitter);
            app.v02Label.Text = 'v0.2';
            app.SliceLabel = uilabel(app.exponFitter);
            app.SliceLabel.Text = 'Slice';
            % Show the figure after all components are created
            app.exponFitter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iEPR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.exponFitter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.exponFitter)
        end
    end
end