classdef xODNP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        xodnpLab                      matlab.ui.Figure
        T1plot                        matlab.ui.control.UIAxes
        Eplot                         matlab.ui.control.UIAxes
        ksigplot                      matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        AnalysisTab                   matlab.ui.container.Tab
        CuMEditFieldLabel             matlab.ui.control.Label
        Concentration                 matlab.ui.control.NumericEditField
        smaxEditFieldLabel            matlab.ui.control.Label
        smax                          matlab.ui.control.NumericEditField
        T10sEditFieldLabel            matlab.ui.control.Label
        T10                           matlab.ui.control.NumericEditField
        T100sEditField_2Label         matlab.ui.control.Label
        T100                          matlab.ui.control.NumericEditField
        BulklikeCheckBox              matlab.ui.control.CheckBox
        ksigbulk1sMLabel              matlab.ui.control.Label
        ksigbulk                      matlab.ui.control.NumericEditField
        klowbulk1sMLabel              matlab.ui.control.Label
        klowbulk                      matlab.ui.control.NumericEditField
        tcorrbulkpsLabel              matlab.ui.control.Label
        tcorrbulk_cor                 matlab.ui.control.NumericEditField
        FieldmTEditFieldLabel         matlab.ui.control.Label
        FieldmT                       matlab.ui.control.NumericEditField
        AnalysisList                  matlab.ui.control.ListBox
        CalculateButton               matlab.ui.control.Button
        krhobulk1sMLabel              matlab.ui.control.Label
        krhobulk                      matlab.ui.control.NumericEditField
        AdvancedTab                   matlab.ui.container.Tab
        HeatingEffectsLabel           matlab.ui.control.Label
        deltaT100CheckBox             matlab.ui.control.CheckBox
        deltaT100EditField            matlab.ui.control.NumericEditField
        seffpButton                   matlab.ui.control.StateButton
        taurotCheckBox                matlab.ui.control.CheckBox
        tau_rotpsLabel                matlab.ui.control.Label
        tau_rotpsEditField            matlab.ui.control.NumericEditField
        boundwaterLabel               matlab.ui.control.Label
        boundwaterEditField           matlab.ui.control.NumericEditField
        Macromoleculetautau_rotLabel  matlab.ui.control.Label
        TatpminKLabel                 matlab.ui.control.Label
        TempLow                       matlab.ui.control.NumericEditField
        useModel                      matlab.ui.control.CheckBox
        AdvancedListBox               matlab.ui.control.ListBox
        TatpmaxKLabel                 matlab.ui.control.Label
        TempHigh                      matlab.ui.control.NumericEditField
        C_macrouMLabel                matlab.ui.control.Label
        CmacroEditField               matlab.ui.control.NumericEditField
        T1wsLabel                     matlab.ui.control.Label
        T1wsEditField                 matlab.ui.control.NumericEditField
        linearFitCheckBox             matlab.ui.control.CheckBox
        deltaT1wEditField             matlab.ui.control.NumericEditField
        SmoothEpCheckBox              matlab.ui.control.CheckBox
        ExcludefirstT1p               matlab.ui.control.CheckBox
        T1interpolationLabel          matlab.ui.control.Label
        deltaT1wLabel                 matlab.ui.control.Label
        wrkupksigmaspCheckBox         matlab.ui.control.CheckBox
        DataTableTab                  matlab.ui.container.Tab
        DataTable                     matlab.ui.control.Table
        WrkspcButton                  matlab.ui.control.Button
        wrkspcName                    matlab.ui.control.EditField
        xlsButton                     matlab.ui.control.Button
        AdjustTab                     matlab.ui.container.Tab
        Adjustk_sigmaspFitLabel       matlab.ui.control.Label
        ksigpp12SliderLabel           matlab.ui.control.Label
        ksigpp12Slider                matlab.ui.control.Slider
        ksigpsmaxSliderLabel          matlab.ui.control.Label
        ksigpsmaxSlider               matlab.ui.control.Slider
        ksigp12Edit                   matlab.ui.control.NumericEditField
        ksigsmaxEdit                  matlab.ui.control.NumericEditField
        AdjustEpFitLabel              matlab.ui.control.Label
        Epp12Label                    matlab.ui.control.Label
        Epp12Slider                   matlab.ui.control.Slider
        couplingfactorLabel           matlab.ui.control.Label
        ksiSlider                     matlab.ui.control.Slider
        Epp12Edit                     matlab.ui.control.NumericEditField
        ksiEdit                       matlab.ui.control.NumericEditField
        AdjustT1pFitLabel             matlab.ui.control.Label
        linearEdit                    matlab.ui.control.NumericEditField
        linearfactorLabel             matlab.ui.control.Label
        linearfactorSlider            matlab.ui.control.Slider
        AdjustParametersLabel         matlab.ui.control.Label
        CSliderLabel                  matlab.ui.control.Label
        CSlider                       matlab.ui.control.Slider
        T10SliderLabel                matlab.ui.control.Label
        T10Slider                     matlab.ui.control.Slider
        T100Label                     matlab.ui.control.Label
        T100Slider                    matlab.ui.control.Slider
        AdjustListBox                 matlab.ui.control.ListBox
        boundSliderLabel              matlab.ui.control.Label
        boundSlider                   matlab.ui.control.Slider
        tau_rotSliderLabel            matlab.ui.control.Label
        tau_rotSlider                 matlab.ui.control.Slider
        bpEdit                        matlab.ui.control.NumericEditField
        trEdit                        matlab.ui.control.NumericEditField
        cEdit                         matlab.ui.control.NumericEditField
        t10Edit                       matlab.ui.control.NumericEditField
        t100Edit                      matlab.ui.control.NumericEditField
        smaxEdit                      matlab.ui.control.NumericEditField
        smaxSliderLabel               matlab.ui.control.Label
        smaxSlider                    matlab.ui.control.Slider
        RecenterButton                matlab.ui.control.Button
        ndorderEdit                   matlab.ui.control.NumericEditField
        ndorderfactorSliderLabel      matlab.ui.control.Label
        ndorderfactorSlider           matlab.ui.control.Slider
        offsetEdit                    matlab.ui.control.NumericEditField
        offsetfactorLabel             matlab.ui.control.Label
        offsetfactorSlider            matlab.ui.control.Slider
        UtilitiesTab                  matlab.ui.container.Tab
        MHzEditField_2Label           matlab.ui.control.Label
        MHz                           matlab.ui.control.NumericEditField
        mTLabel                       matlab.ui.control.Label
        B0mT                          matlab.ui.control.NumericEditField
        ConvertLabel                  matlab.ui.control.Label
        GHzlabel                      matlab.ui.control.Label
        GHz                           matlab.ui.control.NumericEditField
        gvaluesEditFieldLabel         matlab.ui.control.Label
        gvaluesEditField              matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        LoadButton                    matlab.ui.control.Button
        v31Label                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        data={}; % data
        odnpLab={}; % results of calculations in the odnpLab python package
        workup={}; % results of calculations in the Barnes "workup" routine
        expData={}; % experimental paramaters. [SL], field, bulk values, T100, etc.
        results={}; % results of the calculations. k_sigma, krho, klow, tcorr, dlocal, etc.
        tags={}; % used for querying various states of the GUI
        apars={}; % used for sliders and edit boxes in the adjust tab
        pth='';
        datafrom='';
        
        omega_e = [];
        omega_H = [];
        omegaRatio = [];
        
        pars4Jfit=[];
        ksp4fit=[];
        
    end
    
    methods (Access = private)
        
        function [p0,Epowers,Ep]=importE(app)
            
            opts = delimitedTextImportOptions("NumVariables", 4);
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["powerW", "Integralreal", "Integralimag", "ExpNum"];
            opts.VariableTypes = ["double", "double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            tbl = readtable([app.pth,'/enhancementPowers.csv'], opts);
            %assignin('base',"Etbl",tbl)
            E_lngth = find(isnan(tbl{:,2}));
            
            powerWEn = tbl.powerW;
            Integralreal = tbl.Integralreal;
            %             Integralimag = tbl.Integralimag;
            %             ExpNum = tbl.ExpNum;
            Epowers=powerWEn(2:E_lngth(1)-1);
            Ep=Integralreal(2:E_lngth(1)-1);
            p0=Integralreal(1);
            
        end
        
        function [T1powers,T1s,T1errors,t10,t10error]=importT1(app)
            
            opts = delimitedTextImportOptions("NumVariables", 4);
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["powerW1", "T_1s", "T_1errors", "ExpNum1"];
            opts.VariableTypes = ["double", "double", "double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            tbl = readtable([app.pth,'/t1Powers.csv'], opts);
            %assignin('base',"T1tbl",tbl)
            T1_lngth = find(isnan(tbl{:,4}));
            
            T(:,1)=tbl.powerW1(1:T1_lngth(1)-2);
            T(:,2)=tbl.T_1s(1:T1_lngth(1)-2);
            T(:,3)=tbl.T_1errors(1:T1_lngth(1)-2);
            T=sortrows(T,1);
            T1powers = T(:,1);
            T1s = T(:,2);
            T1errors = T(:,3);
            
            t10=tbl.T_1s(T1_lngth(1)-1);
            t10error=tbl.T_1errors(T1_lngth(1)-1);
            
        end
        
        function [ksig,ksigerr,ksigseries,ksigerrors]=importksig(app)
            
            opts = delimitedTextImportOptions("NumVariables", 2);
            opts.DataLines = [2, Inf];
            opts.Delimiter = ",";
            opts.VariableNames = ["kSigma", "error1"];
            opts.VariableTypes = ["double", "double"];
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            ksigtbl = readtable([app.pth,'/kSigma.csv'], opts);
            %assignin('base',"ksigtbl",ksigtbl)
            ksig_lngth =length(ksigtbl{:,2});
            
            ksig=ksigtbl.kSigma(1);
            ksigerr=ksigtbl.error1(1);
            ksigseries=ksigtbl.kSigma(5:ksig_lngth);
            ksigerrors=ksigtbl.error1(5:ksig_lngth);
            
        end
        
        function res = findksig(app,var)
            
            ksigsp=(var(1).*app.data.Epowers)./(var(2)+app.data.Epowers);
            res=sum((ksigsp-app.ksp4fit).^2);
            
        end
        
        function res = findksig_adjusted(app,var)
            
            ksigsp=(var(1).*app.data.Epowers)./(app.ksigp12Edit.Value+app.data.Epowers);
            res=sum((ksigsp-app.ksp4fit).^2);
            
        end
        
        function ksigsp = calc_ksig(app,vars)
            
            ksigsp=(vars(1).*app.data.Epowers)./(vars(2)+app.data.Epowers);
            
        end
        
        function res = findksi_unc(app,var)
            
            E=1-((var(1)*app.results.leakage*app.omegaRatio).*...
                ((app.expData.smax.*app.data.Epowers)./(var(2)+app.data.Epowers))).*...
                app.expData.splinear;
            res=sum((E-app.data.orgEp).^2);
            
        end
        
        function res = findtcorr_ffhsF2013(app,var)
            
            zEmH=sqrt(1i*(app.omega_e-app.omega_H)*var);
            zEpH=sqrt(1i*(app.omega_e+app.omega_H)*var);
            zH=sqrt(1i*app.omega_H*var);
            Jdiff=(1+(zEmH/4))/(1+zEmH+((4*(zEmH^2))/9)+((zEmH^3)/9));
            Jsum=(1+(zEpH/4))/(1+zEpH+((4*(zEpH^2))/9)+((zEpH^3)/9));
            JH=(1+(zH/4))/(1+zH+((4*(zH^2))/9)+((zH^3)/9));
            
            ksi_tcorr=((6*real(Jdiff))-real(Jsum))/((6*real(Jdiff))+(3*real(JH))+real(Jsum));
            
            res=(app.pars4Jfit(1)-ksi_tcorr)^2;
            
        end
        
        function res = findtcorr_ffhsB2017(app,var)
            
            zEmH=(app.omega_e-app.omega_H)*var;
            zEpH=(app.omega_e+app.omega_H)*var;
            zH=app.omega_H*var;
            Jdiff=(1+(((5*sqrt(2))/8)*sqrt(zEmH))+((zEmH)/4))/...
                (1+sqrt(2*zEmH)+(zEmH)+((sqrt(2)/3)*((zEmH)^(3/2)))+...
                ((16/81)*((zEmH)^2))+(((4*sqrt(2))/81)*((zEmH)^(5/2)))+...
                (((zEmH)^3)/81));
            Jsum=(1+(((5*sqrt(2))/8)*sqrt(zEpH))+((zEpH)/4))/...
                (1+sqrt(2*zEpH)+(zEpH)+((sqrt(2)/3)*((zEpH)^(3/2)))+...
                ((16/81)*((zEpH)^2))+(((4*sqrt(2))/81)*((zEpH)^(5/2)))+...
                (((zEpH)^3)/81));
            JH=(1+(((5*sqrt(2))/8)*sqrt(zH))+((zH)/4))/...
                (1+sqrt(2*zH)+(zH)+((sqrt(2)/3)*((zH)^(3/2)))+...
                ((16/81)*((zH)^2))+(((4*sqrt(2))/81)*((zH)^(5/2)))+...
                (((zH)^3)/81));
            
            if app.pars4Jfit(2)~=0 && app.pars4Jfit(3)~=0
                JH=(1-(app.pars4Jfit(3)/100))*JH+...
                    ((app.pars4Jfit(3)/100)*(app.pars4Jfit(2)/(1-(1i*app.omega_H*app.pars4Jfit(2)))));
                Jsum=(1-(app.pars4Jfit(3)/100))*Jsum+...
                    ((app.pars4Jfit(3)/100)*(app.pars4Jfit(2)/(1-(1i*(app.omega_e+app.omega_H)*app.pars4Jfit(2)))));
                Jdiff=(1-(app.pars4Jfit(3)/100))*Jdiff+...
                    ((app.pars4Jfit(3)/100)*(app.pars4Jfit(2)/(1-(1i*(app.omega_e-app.omega_H)*app.pars4Jfit(2)))));
            end
            
            ksi_tcorr=((6*real(Jdiff))-real(Jsum))/((6*real(Jdiff))+(3*real(JH))+real(Jsum));
            
            res=(app.pars4Jfit(1)-ksi_tcorr)^2;
            
        end
        
        function calculate(app)
            
            table(:,1)=app.data.Epowers;
            table(:,3)=app.data.orgEp;
            
            app.expData.Field = get(app.FieldmT,'Value');
            app.omega_H = ((2.6752218744e-4)*app.expData.Field)/1000;
            app.omega_e = ((1.76085963023e-1)*app.expData.Field)/1000;
            %app.omega_e = app.omega_H/1.51671e-3;
            app.omegaRatio = ((app.omega_e/(2*pi))/(app.omega_H/(2*pi)));
            
            app.expData.C = get(app.Concentration,'Value'); %uM
            slC=app.expData.C/1e6;
            
            if app.expData.C==0
                set(app.AnalysisList,'Items',{' ','<---- Input [C]'})
                return
            end
            
            chkval = get(app.BulklikeCheckBox,'Value');
            switch chkval
                case 0
                    app.expData.smax=1;
                case 1
                    app.expData.smax=1-(2/(3+(3*(slC*198.7))));
            end
            
            set(app.smax,'Value',app.expData.smax);
            set(app.smaxEdit,'Value',app.expData.smax);
            set(app.smaxSlider,'Value',app.expData.smax)
            
            app.data.T100 = get(app.T100,'Value');
            app.data.T10 = get(app.T10,'Value');
            
            set(app.T100Slider,'Limits',[app.data.T100/2,...
                app.data.T100*1.5],'Value',app.data.T100)
            set(app.t100Edit,'value',app.data.T100)
            set(app.T10Slider,'Limits',[app.data.T10/2,...
                app.data.T10*1.5],'Value',app.data.T10)
            set(app.t10Edit,'value',app.data.T10)
            set(app.CSlider,'Limits',[app.expData.C/2,...
                app.expData.C*1.5],'Value',app.expData.C)
            set(app.cEdit,'value',app.expData.C)
            
            app.expData.k_rho_bulk = get(app.krhobulk,'Value');
            app.expData.k_sig_bulk = get(app.ksigbulk,'Value');
            app.expData.k_low_bulk = get(app.klowbulk,'Value');
            app.expData.tcorr_bulk = get(app.tcorrbulk_cor,'Value');
            
            app.expData.Cmacro = get(app.CmacroEditField,'Value');
            app.data.T1w = get(app.T1wsEditField,'Value');
            
            switch app.ExcludefirstT1p.Value
                case 0
                    fitT1p = app.data.orgT1p;
                    fitT1powers = app.data.T1powers;
                case 1
                    fitT1p = app.data.orgT1p(2:end);
                    fitT1powers = app.data.T1powers(2:end);
                    
            end
            set(app.offsetfactorSlider,'Value',1)
            set(app.offsetEdit,'Value',1)
            set(app.linearfactorSlider,'Value',1)
            set(app.linearEdit,'Value',1)
            set(app.ndorderfactorSlider,'Value',1)
            set(app.ndorderEdit,'Value',1)
            
            try
                app.data.delT1p=((app.data.orgT1p(end)-app.data.orgT1p(1))/app.data.orgT1p(1))*100;
                app.data.delT1w = get(app.deltaT1wEditField,'Value');
                
                app.data.pT1 = [];
                switch get(app.linearFitCheckBox,'Value')
                    case 0
                        
                        if app.expData.Cmacro==0
                            app.expData.Cmacro = app.expData.C;
                            set(app.CmacroEditField,'Value',app.expData.Cmacro)
                        end
                        if app.data.T1w==0
                            app.data.T1w = 2.5;
                            set(app.T1wsEditField,'Value',app.data.T1w)
                        end
                        if app.data.delT1w==0
                            app.data.delT1w = (app.data.delT1p/100)*app.data.orgT1p(1);
                            set(app.deltaT1wEditField,'Value',app.data.delT1w)
                        end
                        
                        app.data.k_HH = ((1./app.data.T10) - (1/(app.data.T1w)))./(app.expData.Cmacro/1e6);
                        krp=((1./fitT1p)-(1./(app.data.T1w + app.data.delT1w.*fitT1powers))-...
                            (app.data.k_HH*(app.expData.Cmacro/1e6)))./(app.expData.C/1e6);
                        
                        p = polyfit(fitT1powers,krp,2);
                        flinear = polyval(p,app.data.Epowers);
                        
                        app.data.intT1p = 1./(((app.expData.C/1e6).*flinear)+(1./(app.data.T1w + app.data.delT1w.*app.data.Epowers))+...
                            (app.data.k_HH*(app.expData.Cmacro/1e6)));
                        
                    case 1
                        
                        T1p=1./((1./fitT1p)-(1/app.data.T10)+(1/app.data.T100));
                        
                        p = polyfit(fitT1powers,T1p,1);
                        flinear = polyval(p,app.data.Epowers);
                        
                        app.data.intT1p=flinear./(1+(flinear./app.data.T10)-(flinear./app.data.T100));
                        
                end
                table(:,2)=app.data.intT1p;
                
                app.data.pT1 = p;
                
                app.results.krho = ((1/app.data.T10)-(1/app.data.T100))/slC;
                app.results.krho_ratio = app.results.krho/app.expData.k_rho_bulk;
                app.results.leakage = 1-(app.data.T10/app.data.T100);
                
                delT100=get(app.deltaT100EditField,'Value');
                chkdT100 = get(app.deltaT100CheckBox,'Value');
                if chkdT100==1 && delT100~=0
                    app.expData.splinear = ((1+(app.results.krho*slC*app.data.T100)).*...
                        (1+((delT100/app.data.T100).*app.data.Epowers)))./...
                        (1+(app.results.krho*slC*app.data.T100).*(1+((delT100/app.data.T100).*app.data.Epowers)));
                else
                    app.expData.splinear=linspace(app.expData.smax,app.expData.smax,length(app.data.Epowers))';
                end
                
                %% uncorrected fit
                varsUnc=fminsearch(@app.findksi_unc,[.1 1]);
                app.results.ksi_unc = varsUnc(1);
                app.results.p12_unc = varsUnc(2);
                app.results.EpUnc=1-((app.results.ksi_unc*app.results.leakage*app.omegaRatio).*...
                    ((app.data.Epowers.*app.expData.smax)./(app.results.p12_unc+app.data.Epowers))).*app.expData.splinear;
                
                app.results.seffp = ((app.expData.smax.*app.data.Epowers)./(app.results.p12_unc+app.data.Epowers)).*app.expData.splinear;
                
                if varsUnc(1)>0 && varsUnc(2)>0
                    set(app.ksiSlider,'Limits',[0,...
                        varsUnc(1)*2],'Value',varsUnc(1))
                    set(app.ksiEdit,'Value',varsUnc(1))
                    set(app.Epp12Slider,'Limits',[0,...
                        varsUnc(2)*2],'Value',varsUnc(2))
                    set(app.Epp12Edit,'Value',varsUnc(2))
                end
                
                %% uncorrected fit can also be used as a smoothed version of E[p]
                switch app.SmoothEpCheckBox.Value
                    case 1
                        app.data.Ep = app.results.EpUnc;
                    case 0
                        app.data.Ep = app.data.orgEp;
                end
                
                app.data.ksig_sp=(1-app.data.Ep)./(slC.*app.omegaRatio.*app.data.intT1p);
                table(:,4)=app.data.ksig_sp;
                switch app.wrkupksigmaspCheckBox.Value
                    case 1
                        app.ksp4fit = app.workup.ksig_sp./slC;
                    case 0
                        app.ksp4fit = app.data.ksig_sp;
                end
                
                x0=[95.4/2, (max(app.data.Epowers)*0.1)];
                [result] = fminsearch(@app.findksig,x0);
                app.results.ksigsmax = result(1);
                app.results.p12 = result(2);
                
                set(app.ksigpsmaxSlider,'Limits',[result(1)/2,...
                    result(1)*1.5],'Value',result(1))
                set(app.ksigsmaxEdit,'Value',result(1))
                set(app.ksigpp12Slider,'Limits',[result(2)/2,...
                    result(2)*1.5],'Value',result(2))
                set(app.ksigp12Edit,'Value',result(2))
                
                app.results.ksig_sp_fit = ((app.results.ksigsmax.*app.data.Epowers)./(app.results.p12+app.data.Epowers));
                
                res = (app.results.ksig_sp_fit - app.ksp4fit).^2;
                chi_sq = sum(res);
                red_chi_sq = chi_sq/...
                    (length(app.results.ksig_sp_fit)-length(result));
                
                J = zeros(length(app.results.ksig_sp_fit),length(result));
                for k = 1:length(result)
                    t = 1;
                    resid = 0;
                    while (resid-red_chi_sq)<eps
                        vars = result;
                        vars(k) = result(k)+(result(k)*eps^(1/t));
                        t=t+1;
                        up = calc_ksig(app,vars);
                        resid = sum((app.ksp4fit-up).^2)/...
                            (length(up)-length(result));
                    end
                    shift = (result(k)*eps^(1/t));
                    
                    vars = result;
                    vars(k) = result(k)+shift;
                    up = calc_ksig(app,vars);
                    res_up = (app.ksp4fit-up).^2;
                    
                    vars = result;
                    vars(k) = result(k)-shift;
                    dn = calc_ksig(app,vars);
                    res_dn = (app.ksp4fit-dn).^2;
                    
                    J(:,k) = (abs(res_up-res) + abs(res_dn-res))/abs(2*shift);
                    %J(:,k) = abs(res_up-res)/abs((shift);
                    %J(:,k) = abs(res_dn-res)/abs(shift);
                end
                
                cov_matrix = red_chi_sq .* (1./(J'*J));
                stdd = sqrt(diag(cov_matrix));
                
                app.results.ksig = app.results.ksigsmax/app.expData.smax;
                
                app.results.ksig_ratio = app.results.ksig/app.expData.k_sig_bulk;
                
                app.results.klow=((5*app.results.krho)-(7*app.results.ksig))/3;
                
                app.results.klow_ratio = app.results.klow/app.expData.k_low_bulk;
                
                app.results.ksi = app.results.ksig/app.results.krho;
                
                switch app.taurotCheckBox.Value
                    case 1
                        app.expData.tau_rot=get(app.tau_rotpsEditField,'Value');
                        app.expData.boundperc=get(app.boundwaterEditField,'Value');
                        if app.expData.boundperc>100
                            app.expData.boundperc=100;
                        end
                        
                        if app.expData.tau_rot>0
                            set(app.tau_rotSlider,'Limits',[app.expData.tau_rot/2,...
                                app.expData.tau_rot*1.5],'Value',app.expData.tau_rot)
                            set(app.trEdit,'Value',app.expData.tau_rot)
                        end
                        if app.expData.boundperc>0
                            set(app.boundSlider,'Limits',[app.expData.boundperc/2,...
                                app.expData.boundperc*1.5],'Value',app.expData.boundperc)
                            set(app.bpEdit,'Value',app.expData.boundperc)
                        end
                        app.pars4Jfit=[app.results.ksi, app.expData.tau_rot, app.expData.boundperc];
                        app.results.tcorr = fminbnd(@app.findtcorr_ffhsB2017,0,5000);
                    case 0
                        app.pars4Jfit=app.results.ksi;
                        app.results.tcorr = fminbnd(@app.findtcorr_ffhsF2013,0,5000);
                end
                
                app.results.tcorr_ratio = app.results.tcorr/app.expData.tcorr_bulk;
                
                dH2O = 2.3e-9;
                dSL = 4.1e-10;
                app.results.dLocal = (app.expData.tcorr_bulk/app.results.tcorr)*(dH2O+dSL);
                
            catch
                set(app.AnalysisList,'Items',{'Calculations Failed,','confirm your concentration','confirm your T1,0','confirm your T1,0,0'})
                set(app.AdvancedListBox,'Items',{'Calculations Failed,','confirm your concentration','confirm your T1,0','confirm your T1,0,0'})
                return
            end
            
            switch app.seffpButton.Value
                case 1
                    plot(app.T1plot,app.data.Epowers,app.results.seffp,'-o', "LineWidth",1.5);
                    hold(app.T1plot,'on');
                    plot(app.T1plot,app.data.Epowers,app.expData.splinear,':', "LineWidth",1.5,'color','b');
                    hold(app.T1plot,'off');
                    xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'effective s(p)');
                    ylim(app.T1plot,[0 1.1]);
                    legend(app.T1plot,'Effective Saturation Factor, s(p)','Linear dependence','location','southeast')
                case 0
                    plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
                    hold(app.T1plot,'on');
                    plot(app.T1plot,app.data.Epowers,app.data.intT1p, "LineWidth",1.5);
                    hold(app.T1plot,'off');
                    ylim(app.T1plot,[min(app.data.intT1p)*.98 max(app.data.intT1p)*1.02]);
                    xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
                    legend(app.T1plot,'T1[p]','Interpolated T1[p]','location','northwest');
            end
            
            plot(app.Eplot,app.data.Epowers,app.data.Ep,'-o', "LineWidth",1.5);
            hold(app.Eplot,'on');
            plot(app.Eplot,app.data.Epowers,app.results.EpUnc, "LineWidth",1.5);
            hold(app.Eplot,'off');
            xlabel(app.Eplot,'microwave power'); ylabel(app.Eplot,'Enhancements');
            legend(app.Eplot,'Enhancements','E[p] Fit','location','northeast');
            
            plot(app.ksigplot,app.data.Epowers,app.data.ksig_sp,'-o','LineWidth',1.5)
            hold(app.ksigplot,'on');
            plot(app.ksigplot,app.data.Epowers,app.results.ksig_sp_fit,'LineWidth',1.5)
            xlabel(app.ksigplot,'microwave power'); ylabel(app.ksigplot,'k_{sigma}*s(p) (1/s*M)');
            
            p12line_cor(1:10)=app.results.p12;
            plot(app.ksigplot,p12line_cor,linspace(min(app.data.ksig_sp),max(app.data.ksig_sp),10),':','LineWidth',1.5,'color','b')
            
            app.data.forcsv=[app.expData.C,app.data.T100,app.data.T10,app.data.delT1p,...
                app.data.Emax,app.results.krho,...
                app.results.ksig,app.results.ksig_ratio,app.results.klow,...
                app.results.klow_ratio,app.results.ksi,app.results.tcorr,app.results.tcorr_ratio,...
                app.results.dLocal];
            
            switch app.datafrom
                case 'workup'
                    
                    clst={'-Hydration parameters-',...
                        ['workup ksig*C = ',num2str(app.workup.ksig/app.expData.smax), '+/-',num2str(app.workup.ksigerr/app.expData.smax),' 1/s'],...
                        ['workup ksig = ',num2str((app.workup.ksig/slC)/app.expData.smax),' 1/(s*M)'],...
                        '--------------',...
                        ['krho = ',num2str(app.results.krho),' 1/(s*M)'],...
                        ['[krho/bulk] = ',num2str(app.results.krho_ratio)],...
                        ['leakage = ',num2str(app.results.leakage)],...
                        ['ksig = ',num2str(app.results.ksig),' 1/(s*M)'],...
                        ['ksig stdd = ', num2str(stdd(1))],...
                        ['[ksig/bulk] = ',num2str(app.results.ksig_ratio)],...
                        ['klow = ',num2str(app.results.klow),' 1/(s*M)'],...
                        ['[klow/bulk] = ',num2str(app.results.klow_ratio)],...
                        ['coupling factor = ',num2str(app.results.ksi)],...
                        ['tcorr = ',num2str(app.results.tcorr), ' ps'],...
                        ['[tcorr/bulk] = ',num2str(app.results.tcorr_ratio)],...
                        ['D = ',num2str(app.results.dLocal), ' (m^2)/s'],...
                        '----------',...
                        ['Emax = ',num2str(app.data.Emax)],...
                        ['% change in T1(p) = ',num2str(app.data.delT1p)],...
                        ['p_1/2 = ',num2str(app.results.p12)],...
                        ['p_max = ',num2str(max(app.data.Epowers))],...
                        };
                    
                    advlst={'-Hydration parameters-',...
                        ['workup ksig*C = ',num2str(app.workup.ksig/app.expData.smax),' 1/s'],...
                        ['workup ksig = ',num2str((app.workup.ksig/slC)/app.expData.smax),' 1/(s*M)'],...
                        ' ',...
                        ['krho = ',num2str(app.results.krho),' 1/(s*M)'],...
                        ['ksig = ',num2str(app.results.ksig),' 1/(s*M)'],...
                        ['klow = ',num2str(app.results.klow),' 1/(s*M)'],...
                        ['coupling factor = ',num2str(app.results.ksi)],...
                        ['tcorr = ',num2str(app.results.tcorr), ' ps'],...
                        ['D = ',num2str(app.results.dLocal), ' (m^2)/s'],...
                        };
                    
                    plot(app.ksigplot,app.data.Epowers,app.workup.ksig_sp./slC,'-x','LineWidth',1.5)
                    legend(app.ksigplot,'k_{sigma}*s(p)','k_{sigma}*s(p) Fit','p_{1/2}','workup k_{sigma}*s(p)','location','southeast');
                    
                    table(:,5)=app.workup.ksig_sp;
                    table(:,6)=app.workup.ksig_sp_errors;
                    
                case 'mat'
                    
                    clst={'-Hydration parameters-',...
                        ['krho = ',num2str(app.results.krho),' 1/(s*M)'],...
                        ['[krho/bulk] = ',num2str(app.results.krho_ratio)],...
                        ['leakage = ',num2str(app.results.leakage)],...
                        ['ksig = ',num2str(app.results.ksig),' 1/(s*M)'],...
                        ['ksig stdd = ', num2str(stdd(1))],...
                        ['[ksig/bulk] = ',num2str(app.results.ksig_ratio)],...
                        ['klow = ',num2str(app.results.klow),' 1/(s*M)'],...
                        ['[klow/bulk] = ',num2str(app.results.klow_ratio)],...
                        ['coupling factor = ',num2str(app.results.ksi)],...
                        ['tcorr = ',num2str(app.results.tcorr), ' ps'],...
                        ['[tcorr/bulk] = ',num2str(app.results.tcorr_ratio)],...
                        ['D = ',num2str(app.results.dLocal), ' (m^2)/s'],...
                        '----------',...
                        ['Emax = ',num2str(app.data.Emax)],...
                        ['% change in T1(p) = ',num2str(app.data.delT1p)],...
                        ['p_1/2 = ',num2str(app.results.p12)],...
                        ['p_max = ',num2str(max(app.data.Epowers))],...
                        };
                    
                    advlst={'-Hydration parameters-',...
                        ['krho = ',num2str(app.results.krho),' 1/(s*M)'],...
                        ['ksig = ',num2str(app.results.ksig),' 1/(s*M)'],...
                        ['klow = ',num2str(app.results.klow),' 1/(s*M)'],...
                        ['coupling factor = ',num2str(app.results.ksi)],...
                        ['tcorr = ',num2str(app.results.tcorr), ' ps'],...
                        ['D = ',num2str(app.results.dLocal), ' (m^2)/s'],...
                        };
                    
                    legend(app.ksigplot,'k_{sigma}*s(p)','k_{sigma}*s(p) Fit','p_{1/2}','location','southeast');
                    
            end
            hold(app.ksigplot,'off');
            
            set(app.AnalysisList,'Items',clst)
            set(app.AdvancedListBox,'Items',advlst)
            
            app.tags.calced='y';
            app.tags.adjp12 = "no";
            app.tags.adjksig = "no";
            
            adjlst={['krho = ',num2str(app.results.krho,'%.0f'),' 1/(s*M)'],...
                ['ksig = ',num2str(app.results.ksig,'%.2f'),' 1/(s*M)'],...
                ['klow = ',num2str(app.results.klow,'%.0f'),' 1/(s*M)'],...
                ['coupling factor = ',num2str(app.results.ksi,'%.4f')],...
                ['tcorr = ',num2str(app.results.tcorr,'%.0f'), ' ps'],...
                ['D = ',num2str(app.results.dLocal,'%.2e'), ' (m^2)/s']
                };
            
            set(app.AdjustListBox,'Items',adjlst)
            
            set(app.DataTable,'Data',table)
            
        end
        
        function calc_adjust(app)
            
            T100value = app.t100Edit.Value;
            T10value = app.t10Edit.Value;
            Cvalue = app.cEdit.Value;
            
            krho = ((1/T10value)-(1/T100value))/(Cvalue/1e6);
            leakage = 1-(T10value/T100value);
            
            switch get(app.linearFitCheckBox,'Value')
                case 0
                    
                    p(1)=app.data.pT1(1)*app.ndorderEdit.Value;
                    p(2)=app.data.pT1(2)*app.linearEdit.Value;
                    p(3)=app.data.pT1(3)*app.offsetEdit.Value;
                    flinear = polyval(p,app.data.Epowers);
                    k_HH = ((1./T10value) - (1/(app.data.T1w)))./(app.expData.Cmacro/1e6);
                    adjustedT1p = 1./(((Cvalue/1e6).*flinear)+(1./(app.data.T1w + app.data.delT1w.*app.data.Epowers))+...
                        (k_HH*(app.expData.Cmacro/1e6)));
                    
                case 1
                    
                    p(1)=app.data.pT1(1)*app.linearEdit.Value;
                    p(2)=app.data.pT1(2)*app.offsetEdit.Value;
                    flinear = polyval(p,app.data.Epowers);
                    adjustedT1p=flinear./(1+(flinear./T10value)-(flinear./T100value));
                    
            end
            
            Epp12 = app.Epp12Edit.Value;
            Epksi = app.ksiEdit.Value;
            s_max = app.smaxEdit.Value;
            
            Epcalc=1-((Epksi*leakage*app.omegaRatio).*...
                ((app.data.Epowers)./(Epp12+app.data.Epowers)));
            
            switch app.tags.adjksig
                case "yes"
                    ksig_sp_fit = ((app.ksigsmaxEdit.Value.*app.data.Epowers)./(app.ksigp12Edit.Value+app.data.Epowers));
                    ksigsmax = app.ksigsmaxEdit.Value;
                case "no"
                    app.ksp4fit=(1-Epcalc)./((Cvalue / 1e6).*app.omegaRatio.*adjustedT1p);
                    
                    switch app.tags.adjp12
                        case "yes"
                            x0=95.4/2;
                            [result] = fminsearch(@app.findksig_adjusted,x0);
                            ksigsmax = result(1);
                            
                            ksig_sp_fit = ((ksigsmax.*app.data.Epowers)./(app.ksigp12Edit.Value+app.data.Epowers));
                            set(app.ksigpsmaxSlider,"Value",ksigsmax)
                            
                        case "no"
                            x0=[95.4/2, (max(app.data.Epowers)*0.1)];
                            [result] = fminsearch(@app.findksig,x0);
                            ksigsmax = result(1);
                            p12 = result(2);
                            
                            ksig_sp_fit = ((ksigsmax.*app.data.Epowers)./(p12+app.data.Epowers));
                    end
            end
            ksig = ksigsmax / s_max;
            klow=((5*krho)-(7*ksig))/3;
            ksi = ksig/krho;
            
            switch app.taurotCheckBox.Value
                case 1
                    taurot = app.trEdit.Value;
                    boundperc = app.bpEdit.Value;
                    app.pars4Jfit=[ksi, taurot, boundperc];
                    tcorr = fminbnd(@app.findtcorr_ffhsB2017,0,5000);
                case 0
                    app.pars4Jfit=ksi;
                    tcorr = fminbnd(@app.findtcorr_ffhsF2013,0,5000);
            end
            
            dH2O = 2.3e-9;
            dSL = 4.1e-10;
            dLocal = (app.expData.tcorr_bulk/tcorr)*(dH2O+dSL);
            
            lst={['krho = ',num2str(krho,'%.0f'),' 1/(s*M)'],...
                ['ksig = ',num2str(ksig,'%.2f'),' 1/(s*M)'],...
                ['klow = ',num2str(klow,'%.0f'),' 1/(s*M)'],...
                ['coupling factor = ',num2str(ksi,'%.4f')],...
                ['tcorr = ',num2str(tcorr,'%.0f'), ' ps'],...
                ['D = ',num2str(dLocal,'%.2e'), ' (m^2)/s']
                };
            
            set(app.AdjustListBox,'Items',lst)
            
            plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
            hold(app.T1plot,'on');
            plot(app.T1plot,app.data.Epowers,adjustedT1p,"LineWidth",1.5);
            hold(app.T1plot,'off');
            xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
            legend(app.T1plot,'T1[p]','Adjusted T1[p]','location','northwest')
            
            plot(app.Eplot,app.data.Epowers,app.data.Ep,'-o', "LineWidth",1.5)
            hold(app.Eplot,'on')
            plot(app.Eplot,app.data.Epowers,Epcalc, "LineWidth",1.5)
            hold(app.Eplot,'off')
            xlabel(app.Eplot,'microwave power'); ylabel(app.Eplot,'Enhancements');
            legend(app.Eplot,'Enhancements','Adjusted Enhancements','location','northeast')
            
            plot(app.ksigplot,app.data.Epowers,app.data.ksig_sp,'-o','LineWidth',1.5)
            hold(app.ksigplot,'on');
            plot(app.ksigplot,app.data.Epowers,ksig_sp_fit,'LineWidth',1.5)
            hold(app.ksigplot,'off');
            xlabel(app.ksigplot,'microwave power'); ylabel(app.ksigplot,'k_{sigma}*s(p) (1/s*M)');
            legend(app.ksigplot,'k_{sigma}*s(p)',' Adjusted k_{sigma}*s(p)','location','southeast');
            
            app.tags.adjp12 = "no";
            app.tags.adjksig = "no";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Opening(app)
            
            scrn = get(groot,'ScreenSize');
            drawnow
            app.xodnpLab.Position = ...
                [scrn(3)*.07 scrn(4)*.12 scrn(3)*.8333 scrn(4)*.8];
            
            app.tags.calced='n';
            app.tags.dataloaded='n';
            
            lst={'-Hydration parameters-',...
                'krho = ',...
                '[krho/bulk] = ',...
                'leakage = ',...
                'ksig = ',...
                '[ksig/bulk] = ',...
                'klow = ',...
                '[klow/bulk] = ',...
                'coupling factor = ',...
                'tcorr = ',...
                '[tcorr/bulk] = ',...
                'D = ',...
                '----------',...
                'Emax = ',...
                '% change in T1(p) = ',...
                'p_1/2 = ',...
                'p_max = '
                };
            
            set(app.AnalysisList,'Items',lst)
            app.wrkupksigmaspCheckBox.Visible = 'Off';
            
        end

        % Button pushed function: LoadButton
        function LoadData(app, event)
            
            app.xodnpLab.Visible = 'Off';
            try
                [filename,app.pth]=uigetfile({'*.*'});
                [rootnm,exten]=strtok(filename,'.');
            catch
                app.xodnpLab.Visible = 'On';
            end
            app.xodnpLab.Visible = 'On';
            switch rootnm
                case 0
                    return
            end
            
            app.data={}; % data
            app.odnpLab={}; % results of calculations in the odnpLab python package
            app.workup={}; % results of calculations in the Barnes "workup" routine
            app.expData={}; % experimental paramaters. [SL], field, bulk values, T100, etc.
            app.results={}; % results of the calculations. k_sigma, krho, klow, tcorr, dlocal, etc.
            app.tags={}; % used for querying various states of the GUI
            app.apars={}; % used for sliders and edit boxes in the adjust tab
            
            app.pars4Jfit=[];
            app.ksp4fit=[];
            
            switch exten
                case '.csv'
                    set(app.Label,'Text',app.pth)
                    
                    [~,Epowers,Ep]=importE(app);
                    
                    [T1powers,T1p,T1errors,app.data.T10,...
                        app.workup.t10error]=importT1(app);
                    app.data.T100=2.5;
                    
                    [app.workup.ksig,app.workup.ksigerr,ksig_sp,...
                        ksig_error]=importksig(app);
                    app.workup.ksig_sp=[];
                    app.workup.ksig_sp_errors=[];
                    
                    app.datafrom='workup';
                    set(app.wrkupksigmaspCheckBox,'Visible','on')
                    
                case '.mat'
                    set(app.Label,'Text',filename)
                    indata=load(strcat(app.pth,filesep,filename));
                    Epowers=indata.odnp.Epowers;
                    Ep=indata.odnp.Ep;
                    
                    T1powers=indata.odnp.T1powers;
                    T1p=indata.odnp.T1p;
                    app.data.T10=indata.odnp.T10;
                    app.data.T100=indata.odnp.T100;
                    
                    app.datafrom='mat';
                    set(app.wrkupksigmaspCheckBox,'Visible','off','Value',0)
                    
            end
            
            tblE(:,1)=Epowers;
            tblE(:,2)=Ep;
            switch app.datafrom
                case 'workup'
                    tblE(:,3)=ksig_sp;
                    tblE(:,4)=ksig_error;
                    tblT1(:,3)=T1errors;
            end
            tblE=sortrows(tblE,1);
            app.data.Epowers=tblE(:,1);
            app.data.orgEp=tblE(:,2);
            tblT1(:,1)=T1powers;
            tblT1(:,2)=T1p;
            tblT1=sortrows(tblT1,1);
            app.data.orgT1p=tblT1(:,2);
            app.data.T1powers=tblT1(:,1);
            
            app.data.Emax=min(app.data.orgEp);
            
            switch app.datafrom
                case 'workup'
                    
                    app.workup.ksig_sp=tblE(:,3);
                    app.workup.ksig_sp_errors=tblE(:,4);
                    
                    set(app.DataTable,'ColumnName',{'MW Power','T1[p]','E[p]','ksig*s[p]','ksig*s[p]*C workup','ksig*s[p]*C errors'})
                    
                    plot(app.ksigplot,app.data.Epowers,app.workup.ksig_sp, '-o',"LineWidth",1.5,'Color',[0.47,0.67,0.19])
                    xlabel(app.ksigplot,'microwave power'); ylabel(app.ksigplot,'k_{sigma}*s(p) (1/s*M)');
                    legend(app.ksigplot,'workup k_{sigma}*s(p)','location','southeast');
                    
                    lst={'-Hydration parameters-',...
                        'krho = ',' 1/(s*M)',...
                        '[krho/bulk] = ',...
                        'leakage = ',...
                        ['workup ksig*C*smax = ',num2str(app.workup.ksig), '+/-',num2str(app.workup.ksigerr),' 1/s'],...
                        ['ksig = ',' 1/(s*M)'],...
                        '[ksig/bulk] = ',...
                        ['klow = ',' 1/(s*M)'],...
                        '[klow/bulk] = ',...
                        'coupling factor = ',...
                        ['tcorr = ',' ps'],...
                        '[tcorr/bulk] = ',...
                        ['D = ',' (m^2)/s'],...
                        '----------',...
                        ['Emax = ',num2str(app.data.Emax)],...
                        '% change in T1(p) = ',...
                        'p_1/2 = ',...
                        ['p_max = ',num2str(max(app.data.Epowers))]
                        };
                    
                case 'mat'
                    
                    set(app.T100,'Value',app.data.T100)
                    
                    set(app.DataTable,'ColumnName',{'MW Power','T1[p]','E[p]','ksig*s[p]'})
                    
                    lst={'-Hydration parameters-',...
                        'krho = ',' 1/(s*M)',...
                        '[krho/bulk] = ',...
                        'leakage = ',...
                        ['ksig = ',' 1/(s*M)'],...
                        '[ksig/bulk] = ',...
                        ['klow = ',' 1/(s*M)'],...
                        '[klow/bulk] = ',...
                        'coupling factor = ',...
                        ['tcorr = ',' ps'],...
                        '[tcorr/bulk] = ',...
                        ['D = ',' (m^2)/s'],...
                        '----------',...
                        'p_1/2 = ',...
                        ['Emax = ',num2str(app.data.Emax)],...
                        '% change in T1(p) = ',...
                        ['p_max = ',num2str(max(app.data.Epowers))]
                        };
                    
            end
            
            set(app.T10,'Value',app.data.T10)
            
            plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5,'Color',[0.00,0.45,0.74])
            xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
            legend(app.T1plot,'Interpolated T1s','location','northwest');
            
            plot(app.Eplot,app.data.Epowers,app.data.orgEp,'-o', "LineWidth",1.5,'Color',[0.64,0.08,0.18])
            xlabel(app.Eplot,'microwave power'); ylabel(app.Eplot,'Enhancements');
            legend(app.Eplot,'Enhancements','location','northeast');
            
            set(app.AnalysisList,'Items',lst)
            
            set(app.SmoothEpCheckBox,'Value',0)
            
            app.tags.calced='n';
            app.tags.dataloaded='y';
            drawnow
            
            Calc_corr(app)
            
        end

        % Callback function: BulklikeCheckBox, CalculateButton, 
        % CmacroEditField, Concentration, ExcludefirstT1p, FieldmT, 
        % RecenterButton, SmoothEpCheckBox, T10, T100, 
        % T1wsEditField, boundwaterEditField, deltaT100CheckBox, 
        % deltaT100EditField, deltaT1wEditField, klowbulk, 
        % ksigbulk, linearFitCheckBox, smax, tau_rotpsEditField, 
        % taurotCheckBox, tcorrbulk_cor, wrkupksigmaspCheckBox
        function Calc_corr(app, event)
            
            switch app.tags.dataloaded
                case 'n'
                    set(app.AnalysisList,'Items',{'Load your data'})
                    chkval = app.BulklikeCheckBox.Value;
                    switch chkval
                        case 1
                            C = get(app.Concentration,'Value');
                            slC=C/1e6;
                            s_max = 1-(2/(3+(3*(slC*198.7))));
                            set(app.smax,'Value',s_max);
                        case 0
                            set(app.smax,'Value',1);
                    end
                case 'y'
                    calculate(app)
            end
            
            
        end

        % Value changing function: ksigpsmaxSlider
        function adjustingksig(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            
            changingValueksigsmax = event.Value;
            valueksigp12 = app.ksigp12Edit.Value;
            
            ksigplt=((changingValueksigsmax.*app.data.Epowers)./...
                (valueksigp12+app.data.Epowers));
            
            switch app.wrkupksigmaspCheckBox.Value
                case 1
                    ksp4plt = app.workup.ksig_sp./(app.cEdit.Value/1e6);
                case 0
                    ksp4plt = app.data.ksig_sp;
            end
            
            plot(app.ksigplot,app.data.Epowers,ksp4plt,'-o','linewidth',1.5)
            hold(app.ksigplot,'on')
            plot(app.ksigplot,app.data.Epowers,ksigplt,'linewidth',1.5)
            hold(app.ksigplot,'off')
            legend(app.ksigplot,'k_{sigma}*s(p)','Adjusted k_{sigma}*s(p)','location','southeast');
            
            set(app.ksigsmaxEdit,'Value',changingValueksigsmax)
            
        end

        % Value changing function: ksigpp12Slider
        function adjustingksigp12(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            
            changingValuep12 = event.Value;
            valueksigsmax = app.ksigsmaxEdit.Value;
            
            ksigplt=((valueksigsmax.*app.data.Epowers)./...
                (changingValuep12+app.data.Epowers));
            
            switch app.wrkupksigmaspCheckBox.Value
                case 1
                    ksp4plt = app.workup.ksig_sp./(app.cEdit.Value/1e6);
                case 0
                    ksp4plt = app.data.ksig_sp;
            end
            
            plot(app.ksigplot,app.data.Epowers,ksp4plt,'-o','linewidth',1.5)
            hold(app.ksigplot,'on')
            plot(app.ksigplot,app.data.Epowers,ksigplt,'linewidth',1.5)
            hold(app.ksigplot,'off')
            legend(app.ksigplot,'k_{sigma}*s(p)','Adjusted k_{sigma}*s(p)','location','southeast');
            
            set(app.ksigp12Edit,'Value',changingValuep12)
            
        end

        % Value changed function: ksigpsmaxSlider, ksigsmaxEdit
        function adjusted_ksig(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AdjustListBox,'Items',{'Calculate First'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            app.tags.adjksig = "yes";
            calc_adjust(app)
            
        end

        % Value changed function: ksigp12Edit, ksigpp12Slider
        function adjusted_ksigp12(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AdjustListBox,'Items',{'Calculate First'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            app.tags.adjp12 = "yes";
            calc_adjust(app)
            
        end

        % Value changing function: Epp12Slider
        function adjustingEpp12(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            
            changingEpp12Value = event.Value;
            Epksi = app.ksiEdit.Value;
            
            T100value = app.t100Edit.Value;
            T10value = app.t10Edit.Value;
            leakage = 1-(T10value/T100value);
            
            Epcalc=1-((Epksi*leakage*app.omegaRatio).*...
                ((app.data.Epowers.*app.expData.smax)./(changingEpp12Value+app.data.Epowers)));
            
            plot(app.Eplot,app.data.Epowers,app.data.Ep,'-o', "LineWidth",1.5)
            hold(app.Eplot,'on')
            plot(app.Eplot,app.data.Epowers,Epcalc, "LineWidth",1.5)
            hold(app.Eplot,'off')
            xlabel(app.Eplot,'microwave power'); ylabel(app.Eplot,'Enhancements');
            legend(app.Eplot,'Enhancements','Adjusted Enhancements','location','northeast')
            
            set(app.Epp12Edit,'Value',changingEpp12Value)
            
        end

        % Value changing function: ksiSlider
        function adjustingEpksi(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            
            changingEpksiValue = event.Value;
            Epp12 = app.Epp12Edit.Value;
            
            T100value = app.t100Edit.Value;
            T10value = app.t10Edit.Value;
            leakage = 1-(T10value/T100value);
            
            Epcalc=1-((changingEpksiValue*leakage*app.omegaRatio).*...
                ((app.data.Epowers.*app.expData.smax)./(Epp12+app.data.Epowers)));
            
            plot(app.Eplot,app.data.Epowers,app.data.Ep,'-o', "LineWidth",1.5)
            hold(app.Eplot,'on')
            plot(app.Eplot,app.data.Epowers,Epcalc, "LineWidth",1.5)
            hold(app.Eplot,'off')
            xlabel(app.Eplot,'microwave power'); ylabel(app.Eplot,'Enhancements');
            legend(app.Eplot,'Enhancements','Adjusted Enhancements','location','northeast')
            
            set(app.ksiEdit,'Value',changingEpksiValue)
            
        end

        % Value changing function: offsetfactorSlider
        function adjustingT1p_offset(app, event)
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            
            changingValue = event.Value;
            set(app.offsetEdit,'Value',changingValue)
            
            switch get(app.linearFitCheckBox,'Value')
                case 0
                    
                    p(1) = app.data.pT1(1)*app.ndorderEdit.Value;
                    p(2) = app.data.pT1(2)*app.linearEdit.Value;
                    p(3) = app.data.pT1(3)*changingValue;
                    flinear = polyval(p,app.data.Epowers);
                    k_HH = ((1./app.t10Edit.Value) - (1/(app.data.T1w)))./(app.expData.Cmacro/1e6);
                    adjustedT1p = 1./(((app.cEdit.Value/1e6).*flinear)+(1./(app.data.T1w + app.data.delT1w.*app.data.Epowers))+...
                        (k_HH*(app.expData.Cmacro/1e6)));
                case 1
                    
                    p(1) = app.data.pT1(1)*app.linearEdit.Value;
                    p(2) = app.data.pT1(2)*changingValue;
                    flinear = polyval(p,app.data.Epowers);
                    adjustedT1p=flinear./(1+(flinear./app.t10Edit.Value)-(flinear./app.t100Edit.Value));
            end
            
            plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
            hold(app.T1plot,'on');
            plot(app.T1plot,app.data.Epowers,adjustedT1p,"LineWidth",1.5);
            hold(app.T1plot,'off');
            xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
            legend(app.T1plot,'T1[p]','Adjusted T1[p]','location','northwest')
            
        end

        % Value changing function: linearfactorSlider
        function adjustingT1p_linear(app, event)
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            
            changingValue = event.Value;
            set(app.linearEdit,'Value',changingValue)
            
            switch get(app.linearFitCheckBox,'Value')
                case 0
                    
                    p(1) = app.data.pT1(1)*app.ndorderEdit.Value;
                    p(2) = app.data.pT1(2)*changingValue;
                    p(3) = app.data.pT1(3)*app.offsetEdit.Value;
                    flinear = polyval(p,app.data.Epowers);
                    k_HH = ((1./app.t10Edit.Value) - (1/(app.data.T1w)))./(app.expData.Cmacro/1e6);
                    adjustedT1p = 1./(((app.cEdit.Value/1e6).*flinear)+(1./(app.data.T1w + app.data.delT1w.*app.data.Epowers))+...
                        (k_HH*(app.expData.Cmacro/1e6)));
                case 1
                    
                    p(1) = app.data.pT1(1)*changingValue;
                    p(2) = app.data.pT1(2)*app.offsetEdit.Value;
                    flinear = polyval(p,app.data.Epowers);
                    adjustedT1p=flinear./(1+(flinear./app.t10Edit.Value)-(flinear./app.t100Edit.Value));
            end
            
            plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
            hold(app.T1plot,'on');
            plot(app.T1plot,app.data.Epowers,adjustedT1p,"LineWidth",1.5);
            hold(app.T1plot,'off');
            xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
            legend(app.T1plot,'T1[p]','Adjusted T1[p]','location','northwest')
            
            
        end

        % Value changing function: ndorderfactorSlider
        function adjustingT1p_ndorder(app, event)
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            
            switch get(app.linearFitCheckBox,'Value')
                case 1
                    return
                case 0
                    changingValue = event.Value;
                    set(app.ndorderEdit,'Value',changingValue)
                    
                    p(1) = app.data.pT1(1)*changingValue;
                    p(2) = app.data.pT1(2)*app.linearEdit.Value;
                    p(3) = app.data.pT1(3)*app.offsetEdit.Value;
                    flinear = polyval(p,app.data.Epowers);
                    
                    k_HH = ((1./app.t10Edit.Value) - (1/(app.data.T1w)))./(app.expData.Cmacro/1e6);
                    adjustedT1p = 1./(((app.cEdit.Value/1e6).*flinear)+(1./(app.data.T1w + app.data.delT1w.*app.data.Epowers))+...
                        (k_HH*(app.expData.Cmacro/1e6)));
                    
                    plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
                    hold(app.T1plot,'on');
                    plot(app.T1plot,app.data.Epowers,adjustedT1p,"LineWidth",1.5);
                    hold(app.T1plot,'off');
                    xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
                    legend(app.T1plot,'T1[p]','Adjusted T1[p]','location','northwest')
                    
            end
            
            
        end

        % Value changed function: CSlider, Epp12Edit, Epp12Slider, 
        % T100Slider, T10Slider, boundSlider, ksiEdit, ksiSlider, 
        % linearEdit, linearfactorSlider, ndorderEdit, 
        % ndorderfactorSlider, offsetEdit, offsetfactorSlider, 
        % smaxSlider, tau_rotSlider
        function adjustedParams(app, event)
            switch app.tags.calced
                case 'n'
                    set(app.AdjustListBox,'Items',{'Calculate First'})
                    return
            end
            switch app.seffpButton.Value
                case 1
                    set(app.seffpButton,'Value',0,'Text','s,eff (p)')
                    cla(app.T1plot,'reset')
            end
            
            calc_adjust(app)
            
        end

        % Value changing function: T100Slider
        function adjustingT100(app, event)
            changingValue = event.Value;
            set(app.t100Edit,'value',changingValue)
        end

        % Value changing function: T10Slider
        function adjustingT10(app, event)
            changingValue = event.Value;
            set(app.t10Edit,'value',changingValue)
        end

        % Value changing function: CSlider
        function adjustingC(app, event)
            changingValue = event.Value;
            set(app.cEdit,'value',changingValue)
        end

        % Value changing function: smaxSlider
        function adjustingsmax(app, event)
            changingValue = event.Value;
            set(app.smaxEdit,'value',changingValue)
        end

        % Value changing function: tau_rotSlider
        function adjustingtrot(app, event)
            switch app.taurotCheckBox.Value
                case 1
                    changingValue = event.Value;
                    set(app.trEdit,'value',changingValue)
            end
        end

        % Value changing function: boundSlider
        function adjustingbp(app, event)
            switch app.taurotCheckBox.Value
                case 1
                    changingValue = event.Value;
                    set(app.bpEdit,'Value',changingValue)
            end
        end

        % Value changed function: seffpButton
        function showeffsp(app, event)
            
            switch app.tags.calced
                case 'n'
                    set(app.AnalysisList,'Items',{'Calculate First       ^'})
                    return
            end
            
            switch app.seffpButton.Value
                case 1
                    plot(app.T1plot,app.data.Epowers,app.results.seffp,'-o', "LineWidth",1.5);
                    hold(app.T1plot,'on');
                    plot(app.T1plot,app.data.Epowers,app.expData.splinear,':', "LineWidth",1.5,'color','b');
                    hold(app.T1plot,'off');
                    xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'effective s(p)');
                    ylim(app.T1plot,[0 1.1]);
                    legend(app.T1plot,'Effective Saturation Factor, s(p)','Linear dependence','location','southeast')
                    set(app.seffpButton,'Text','T1[p]')
                case 0
                    set(app.seffpButton,'Text','s,eff (p)')
                    plot(app.T1plot,app.data.T1powers,app.data.orgT1p,'-o', "LineWidth",1.5);
                    hold(app.T1plot,'on');
                    plot(app.T1plot,app.data.Epowers,app.data.intT1p, "LineWidth",1.5);
                    hold(app.T1plot,'off');
                    ylim(app.T1plot,[min(app.data.intT1p)*.98 max(app.data.intT1p)*1.02]);
                    xlabel(app.T1plot,'microwave power'); ylabel(app.T1plot,'T1 (s)');
                    legend(app.T1plot,'T1[p]','T1[p] Fit','location','northwest');
            end
            
        end

        % Value changed function: TempHigh, TempLow, useModel
        function tempModel(app, event)
            
            value = get(app.useModel,'Value');
            
            switch value
                case 1
                    A1=(4.6e-9); %1/s
                    A2=(6.3e-4); %1/s
                    B1=4787; %K
                    B2=1764; %K
                    kb=1.380649e-23; % J/K
                    trcsq=4*(pi^2)*1046.7; %1/s^2
                    tri=5.8783e-40; %g*cm^2
                    tcr=12.3e-12; %s
                    h=6.62607015e-34; %J*s
                    
                    tl=get(app.TempLow,'Value');
                    th=get(app.TempHigh,'Value');
                    if tl~=th && tl<th
                        temprange=tl:.1:th;
                        T1=zeros(length(temprange),1);
                        k=1;
                        for t=temprange
                            T1(k)=1/...
                                ((A1*exp(B1/t))...
                                +(A2*exp(B2/t))...
                                +(((2/9)*kb*t*(1/(h^2))*tri*trcsq*tcr)*0.1));
                            k=k+1;
                        end
                        delT=T1(end)-T1(1);
                        set(app.deltaT100EditField,'Value',delT)
                        set(app.deltaT1wEditField,'Value',delT)
                    end
                    
                    T1w=1/...
                        ((A1*exp(B1/tl))...
                        +(A2*exp(B2/tl))...
                        +(((2/9)*kb*tl*(1/(h^2))*tri*trcsq*tcr)*0.1));
                    
                    set(app.T1wsEditField,'Value',T1w)
            end
            drawnow
            
            Calc_corr(app)
            
        end

        % Button pushed function: WrkspcButton
        function add2wrkspc(app, event)
            table=get(app.DataTable,'Data');
            nm = get(app.wrkspcName,'Value');
            assignin('base',nm,table)
            assignin('base',[nm,'_orgT1p'],app.data.orgT1p)
            assignin('base',[nm,'_T1powers'],app.data.T1powers)
        end

        % Button pushed function: xlsButton
        function save2csv(app, event)
            
            csvout = get(app.DataTable,'Data');
            nm = get(app.wrkspcName,'Value');
            writematrix(csvout,[nm,'.xls'],'FileType','spreadsheet')
            
        end

        % Value changed function: MHz
        function mhz_func(app, event)
            mhz = get(app.MHz,'Value');
            g_values = str2num(app.gvaluesEditField.Value);
            
            field = (mhz*2*pi*1e9) / (267.52218744*1e-6);
            ghz = (field*9.2740e-24*mean(g_values)*1e3) / (6.6261e-34*1e9);
            
            set(app.B0mT,'Value',field)
            set(app.GHz, 'Value',ghz)
            
        end

        % Value changed function: GHz
        function ghz_func(app, event)
            ghz_value = app.GHz.Value;
            g_values = str2num(app.gvaluesEditField.Value);
            
            field=(((6.6261e-34)*ghz_value*1e9)./...
                ((9.2740e-24).*mean(g_values))).*1e3;
            
            mhz=(((267.52218744*1e-6)*field/1000)/(2*pi))*1e6;
            
            set(app.MHz,'Value',mhz)
            set(app.B0mT,'Value',field)
            
        end

        % Value changed function: B0mT, gvaluesEditField
        function gvals_func(app, event)
            field = app.B0mT.Value;
            g_values = str2num(app.gvaluesEditField.Value);
            
            ghz = (field*9.2740e-24*mean(g_values)*1e3) / (6.6261e-34*1e9);
            mhz=(((267.52218744*1e-6)*field/1000)/(2*pi))*1e6;
            
            set(app.MHz,'Value',mhz)
            set(app.GHz,'Value',ghz)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.xodnpLab = uifigure('Visible', 'off');
            app.xodnpLab.Name = 'UI Figure';
            app.T1plot = uiaxes(app.xodnpLab);
            title(app.T1plot, '')
            xlabel(app.T1plot, 'X')
            ylabel(app.T1plot, 'Y')
            app.Eplot = uiaxes(app.xodnpLab);
            title(app.Eplot, '')
            xlabel(app.Eplot, 'X')
            ylabel(app.Eplot, 'Y')
            app.ksigplot = uiaxes(app.xodnpLab);
            title(app.ksigplot, '')
            xlabel(app.ksigplot, 'X')
            ylabel(app.ksigplot, 'Y')
            app.TabGroup = uitabgroup(app.xodnpLab);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.CuMEditFieldLabel = uilabel(app.AnalysisTab);
            app.CuMEditFieldLabel.Text = 'C (uM)';
            app.Concentration = uieditfield(app.AnalysisTab, 'numeric');
            app.Concentration.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.smaxEditFieldLabel = uilabel(app.AnalysisTab);
            app.smaxEditFieldLabel.Text = 'smax';
            app.smax = uieditfield(app.AnalysisTab, 'numeric');
            app.smax.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.smax.Value = 1;
            app.T10sEditFieldLabel = uilabel(app.AnalysisTab);
            app.T10sEditFieldLabel.Text = 'T1,0 (s)';
            app.T10 = uieditfield(app.AnalysisTab, 'numeric');
            app.T10.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.T10.Value = 2;
            app.T100sEditField_2Label = uilabel(app.AnalysisTab);
            app.T100sEditField_2Label.Text = 'T1,0,0 (s)';
            app.T100 = uieditfield(app.AnalysisTab, 'numeric');
            app.T100.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.T100.Value = 2.5;
            app.BulklikeCheckBox = uicheckbox(app.AnalysisTab);
            app.BulklikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.BulklikeCheckBox.Text = 'Bulk-like';
            app.ksigbulk1sMLabel = uilabel(app.AnalysisTab);
            app.ksigbulk1sMLabel.Text = 'ksig, bulk 1/(s*M)';
            app.ksigbulk = uieditfield(app.AnalysisTab, 'numeric');
            app.ksigbulk.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.ksigbulk.Value = 95.4;
            app.klowbulk1sMLabel = uilabel(app.AnalysisTab);
            app.klowbulk1sMLabel.Text = 'klow, bulk 1/(s*M)';
            app.klowbulk = uieditfield(app.AnalysisTab, 'numeric');
            app.klowbulk.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.klowbulk.Value = 366;
            app.tcorrbulkpsLabel = uilabel(app.AnalysisTab);
            app.tcorrbulkpsLabel.Text = 'tcorr, bulk (ps)';
            app.tcorrbulk_cor = uieditfield(app.AnalysisTab, 'numeric');
            app.tcorrbulk_cor.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.tcorrbulk_cor.Value = 54;
            app.FieldmTEditFieldLabel = uilabel(app.AnalysisTab);
            app.FieldmTEditFieldLabel.Text = 'Field (mT)';
            app.FieldmT = uieditfield(app.AnalysisTab, 'numeric');
            app.FieldmT.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.FieldmT.Value = 348.5;
            app.AnalysisList = uilistbox(app.AnalysisTab);
            app.AnalysisList.Items = {};
            app.AnalysisList.Value = {};
            app.CalculateButton = uibutton(app.AnalysisTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.CalculateButton.Text = 'Calculate';
            app.krhobulk1sMLabel = uilabel(app.AnalysisTab);
            app.krhobulk1sMLabel.Text = 'krho, bulk 1/(s*M)';
            app.krhobulk = uieditfield(app.AnalysisTab, 'numeric');
            app.krhobulk.Value = 353.4;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.HeatingEffectsLabel = uilabel(app.AdvancedTab);
            app.HeatingEffectsLabel.Text = 'Heating Effects';
            app.deltaT100CheckBox = uicheckbox(app.AdvancedTab);
            app.deltaT100CheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.deltaT100CheckBox.Text = 'deltaT1,0,0 (s)';
            app.deltaT100EditField = uieditfield(app.AdvancedTab, 'numeric');
            app.deltaT100EditField.ValueDisplayFormat = '%11.2g';
            app.deltaT100EditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.seffpButton = uibutton(app.AdvancedTab, 'state');
            app.seffpButton.ValueChangedFcn = createCallbackFcn(app, @showeffsp, true);
            app.seffpButton.Text = 's,eff (p)';
            app.taurotCheckBox = uicheckbox(app.AdvancedTab);
            app.taurotCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.taurotCheckBox.Text = ' I know my macromolecule mobility (tau_rot)';
            app.tau_rotpsLabel = uilabel(app.AdvancedTab);
            app.tau_rotpsLabel.Text = ' tau_rot (ps)';
            app.tau_rotpsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.tau_rotpsEditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.boundwaterLabel = uilabel(app.AdvancedTab);
            app.boundwaterLabel.Text = '% bound water';
            app.boundwaterEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.boundwaterEditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.Macromoleculetautau_rotLabel = uilabel(app.AdvancedTab);
            app.Macromoleculetautau_rotLabel.Text = 'Macromolecule tau, "tau_rot"';
            app.TatpminKLabel = uilabel(app.AdvancedTab);
            app.TatpminKLabel.Text = 'T at p(min) (K) =';
            app.TempLow = uieditfield(app.AdvancedTab, 'numeric');
            app.TempLow.ValueChangedFcn = createCallbackFcn(app, @tempModel, true);
            app.TempLow.Value = 298;
            app.useModel = uicheckbox(app.AdvancedTab);
            app.useModel.ValueChangedFcn = createCallbackFcn(app, @tempModel, true);
            app.useModel.Text = ' Use T1[T] model:';
            app.AdvancedListBox = uilistbox(app.AdvancedTab);
            app.AdvancedListBox.Items = {};
            app.AdvancedListBox.Value = {};
            app.TatpmaxKLabel = uilabel(app.AdvancedTab);
            app.TatpmaxKLabel.Text = 'T at p(max) (K) =';
            app.TempHigh = uieditfield(app.AdvancedTab, 'numeric');
            app.TempHigh.ValueChangedFcn = createCallbackFcn(app, @tempModel, true);
            app.TempHigh.Value = 298;
            app.C_macrouMLabel = uilabel(app.AdvancedTab);
            app.C_macrouMLabel.Text = 'C_macro (uM)';
            app.CmacroEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.CmacroEditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.T1wsLabel = uilabel(app.AdvancedTab);
            app.T1wsLabel.Text = 'T1,w (s)';
            app.T1wsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.T1wsEditField.ValueDisplayFormat = '%11.3g';
            app.T1wsEditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.T1wsEditField.Value = 2.5;
            app.linearFitCheckBox = uicheckbox(app.AdvancedTab);
            app.linearFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.linearFitCheckBox.Text = 'Linear T1[p] Interpolation';
            app.deltaT1wEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.deltaT1wEditField.ValueDisplayFormat = '%11.2g';
            app.deltaT1wEditField.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.SmoothEpCheckBox = uicheckbox(app.AdvancedTab);
            app.SmoothEpCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.SmoothEpCheckBox.Text = 'Smooth E[p]';
            app.ExcludefirstT1p = uicheckbox(app.AdvancedTab);
            app.ExcludefirstT1p.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.ExcludefirstT1p.Text = 'Exclude first T1[p]';
            app.T1interpolationLabel = uilabel(app.AdvancedTab);
            app.T1interpolationLabel.Text = 'T1 interpolation';
            app.deltaT1wLabel = uilabel(app.AdvancedTab);
            app.deltaT1wLabel.Text = 'deltaT1,w (s)';
            app.wrkupksigmaspCheckBox = uicheckbox(app.AdvancedTab);
            app.wrkupksigmaspCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.wrkupksigmaspCheckBox.Text = 'Fit workup ksig*s[p]';
            app.DataTableTab = uitab(app.TabGroup);
            app.DataTableTab.Title = 'Data';
            app.DataTable = uitable(app.DataTableTab);
            app.DataTable.ColumnName = {'MW Power'; 'T1[p]'; 'E [p]'; 'ksigma*s[p]'; ''; ''};
            app.DataTable.RowName = {};
            app.WrkspcButton = uibutton(app.DataTableTab, 'push');
            app.WrkspcButton.ButtonPushedFcn = createCallbackFcn(app, @add2wrkspc, true);
            app.WrkspcButton.Text = 'Wrkspc';
            app.wrkspcName = uieditfield(app.DataTableTab, 'text');
            app.wrkspcName.Value = 'name';
            app.xlsButton = uibutton(app.DataTableTab, 'push');
            app.xlsButton.ButtonPushedFcn = createCallbackFcn(app, @save2csv, true);
            app.xlsButton.Text = '.xls';
            app.AdjustTab = uitab(app.TabGroup);
            app.AdjustTab.Title = 'Adjust';
            app.Adjustk_sigmaspFitLabel = uilabel(app.AdjustTab);
            app.Adjustk_sigmaspFitLabel.Text = 'Adjust k_sigma*s[p] Fit';
            app.ksigpp12SliderLabel = uilabel(app.AdjustTab);
            app.ksigpp12SliderLabel.Text = 'ksig[p] (p1/2)';
            app.ksigpp12Slider = uislider(app.AdjustTab);
            app.ksigpp12Slider.Limits = [0.1 10];
            app.ksigpp12Slider.MajorTicks = [];
            app.ksigpp12Slider.ValueChangedFcn = createCallbackFcn(app, @adjusted_ksigp12, true);
            app.ksigpp12Slider.ValueChangingFcn = createCallbackFcn(app, @adjustingksigp12, true);
            app.ksigpp12Slider.MinorTicks = [];
            app.ksigpp12Slider.Value = 5;
            app.ksigpsmaxSliderLabel = uilabel(app.AdjustTab);
            app.ksigpsmaxSliderLabel.Text = 'ksig[p]*smax';
            app.ksigpsmaxSlider = uislider(app.AdjustTab);
            app.ksigpsmaxSlider.Limits = [0 200];
            app.ksigpsmaxSlider.MajorTicks = [];
            app.ksigpsmaxSlider.ValueChangedFcn = createCallbackFcn(app, @adjusted_ksig, true);
            app.ksigpsmaxSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingksig, true);
            app.ksigpsmaxSlider.MinorTicks = [];
            app.ksigpsmaxSlider.Value = 100;
            app.ksigp12Edit = uieditfield(app.AdjustTab, 'numeric');
            app.ksigp12Edit.ValueChangedFcn = createCallbackFcn(app, @adjusted_ksigp12, true);
            app.ksigsmaxEdit = uieditfield(app.AdjustTab, 'numeric');
            app.ksigsmaxEdit.ValueChangedFcn = createCallbackFcn(app, @adjusted_ksig, true);
            app.AdjustEpFitLabel = uilabel(app.AdjustTab);
            app.AdjustEpFitLabel.Text = 'Adjust E[p] Fit';
            app.Epp12Label = uilabel(app.AdjustTab);
            app.Epp12Label.Text = 'E[p] (p1/2)';
            app.Epp12Slider = uislider(app.AdjustTab);
            app.Epp12Slider.Limits = [0.1 10];
            app.Epp12Slider.MajorTicks = [];
            app.Epp12Slider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.Epp12Slider.ValueChangingFcn = createCallbackFcn(app, @adjustingEpp12, true);
            app.Epp12Slider.MinorTicks = [];
            app.Epp12Slider.Value = 5;
            app.couplingfactorLabel = uilabel(app.AdjustTab);
            app.couplingfactorLabel.Text = 'coupling factor';
            app.ksiSlider = uislider(app.AdjustTab);
            app.ksiSlider.Limits = [0 1];
            app.ksiSlider.MajorTicks = [];
            app.ksiSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.ksiSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingEpksi, true);
            app.ksiSlider.MinorTicks = [];
            app.ksiSlider.Value = 0.5;
            app.Epp12Edit = uieditfield(app.AdjustTab, 'numeric');
            app.Epp12Edit.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.ksiEdit = uieditfield(app.AdjustTab, 'numeric');
            app.ksiEdit.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.AdjustT1pFitLabel = uilabel(app.AdjustTab);
            app.AdjustT1pFitLabel.Text = 'Adjust T1[p] Fit';
            app.linearEdit = uieditfield(app.AdjustTab, 'numeric');
            app.linearEdit.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.linearfactorLabel = uilabel(app.AdjustTab);
            app.linearfactorLabel.Text = 'linear factor';
            app.linearfactorSlider = uislider(app.AdjustTab);
            app.linearfactorSlider.Limits = [0 2];
            app.linearfactorSlider.MajorTicks = [];
            app.linearfactorSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.linearfactorSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingT1p_linear, true);
            app.linearfactorSlider.MinorTicks = [];
            app.linearfactorSlider.Value = 1;
            app.AdjustParametersLabel = uilabel(app.AdjustTab);
            app.AdjustParametersLabel.Text = 'Adjust Parameters';
            app.CSliderLabel = uilabel(app.AdjustTab);
            app.CSliderLabel.Text = '[C]';
            app.CSlider = uislider(app.AdjustTab);
            app.CSlider.Limits = [0 500];
            app.CSlider.MajorTicks = [];
            app.CSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.CSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingC, true);
            app.CSlider.MinorTicks = [];
            app.CSlider.Value = 250;
            app.T10SliderLabel = uilabel(app.AdjustTab);
            app.T10SliderLabel.Text = 'T1,0';
            app.T10Slider = uislider(app.AdjustTab);
            app.T10Slider.Limits = [0 4];
            app.T10Slider.MajorTicks = [];
            app.T10Slider.MajorTickLabels = {''};
            app.T10Slider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.T10Slider.ValueChangingFcn = createCallbackFcn(app, @adjustingT10, true);
            app.T10Slider.MinorTicks = [];
            app.T10Slider.Value = 2;
            app.T100Label = uilabel(app.AdjustTab);
            app.T100Label.Text = 'T1,0,0';
            app.T100Slider = uislider(app.AdjustTab);
            app.T100Slider.Limits = [0 5];
            app.T100Slider.MajorTicks = [];
            app.T100Slider.MajorTickLabels = {''};
            app.T100Slider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.T100Slider.ValueChangingFcn = createCallbackFcn(app, @adjustingT100, true);
            app.T100Slider.MinorTicks = [];
            app.T100Slider.Value = 2.5;
            app.AdjustListBox = uilistbox(app.AdjustTab);
            app.AdjustListBox.Items = {};
            app.AdjustListBox.Value = {};
            app.boundSliderLabel = uilabel(app.AdjustTab);
            app.boundSliderLabel.Text = 'bound %';
            app.boundSlider = uislider(app.AdjustTab);
            app.boundSlider.Limits = [0 50];
            app.boundSlider.MajorTicks = [];
            app.boundSlider.MajorTickLabels = {''};
            app.boundSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.boundSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingbp, true);
            app.boundSlider.MinorTicks = [];
            app.boundSlider.Value = 25;
            app.tau_rotSliderLabel = uilabel(app.AdjustTab);
            app.tau_rotSliderLabel.Text = 'tau_rot';
            app.tau_rotSlider = uislider(app.AdjustTab);
            app.tau_rotSlider.Limits = [0 1000];
            app.tau_rotSlider.MajorTicks = [];
            app.tau_rotSlider.MajorTickLabels = {''};
            app.tau_rotSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.tau_rotSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingtrot, true);
            app.tau_rotSlider.MinorTicks = [];
            app.tau_rotSlider.Value = 500;
            app.bpEdit = uieditfield(app.AdjustTab, 'numeric');
            app.bpEdit.ValueDisplayFormat = '%3.1f';
            app.trEdit = uieditfield(app.AdjustTab, 'numeric');
            app.trEdit.ValueDisplayFormat = '%3.2f';
            app.cEdit = uieditfield(app.AdjustTab, 'numeric');
            app.cEdit.ValueDisplayFormat = '%3.0f';
            app.t10Edit = uieditfield(app.AdjustTab, 'numeric');
            app.t10Edit.ValueDisplayFormat = '%1.2f';
            app.t100Edit = uieditfield(app.AdjustTab, 'numeric');
            app.t100Edit.ValueDisplayFormat = '%1.2f';
            app.smaxEdit = uieditfield(app.AdjustTab, 'numeric');
            app.smaxEdit.ValueDisplayFormat = '%1.2f';
            app.smaxSliderLabel = uilabel(app.AdjustTab);
            app.smaxSliderLabel.Text = 'smax';
            app.smaxSlider = uislider(app.AdjustTab);
            app.smaxSlider.Limits = [0.33 1];
            app.smaxSlider.MajorTicks = [];
            app.smaxSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.smaxSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingsmax, true);
            app.smaxSlider.MinorTicks = [];
            app.smaxSlider.Value = 1;
            app.RecenterButton = uibutton(app.AdjustTab, 'push');
            app.RecenterButton.ButtonPushedFcn = createCallbackFcn(app, @Calc_corr, true);
            app.RecenterButton.Text = 'Re-center';
            app.ndorderEdit = uieditfield(app.AdjustTab, 'numeric');
            app.ndorderEdit.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.ndorderfactorSliderLabel = uilabel(app.AdjustTab);
            app.ndorderfactorSliderLabel.Text = '2nd order factor';
            app.ndorderfactorSlider = uislider(app.AdjustTab);
            app.ndorderfactorSlider.Limits = [0 2];
            app.ndorderfactorSlider.MajorTicks = [];
            app.ndorderfactorSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.ndorderfactorSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingT1p_ndorder, true);
            app.ndorderfactorSlider.MinorTicks = [];
            app.ndorderfactorSlider.Value = 1;
            app.offsetEdit = uieditfield(app.AdjustTab, 'numeric');
            app.offsetEdit.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.offsetfactorLabel = uilabel(app.AdjustTab);
            app.offsetfactorLabel.Text = 'offset factor';
            app.offsetfactorSlider = uislider(app.AdjustTab);
            app.offsetfactorSlider.Limits = [0 2];
            app.offsetfactorSlider.MajorTicks = [];
            app.offsetfactorSlider.ValueChangedFcn = createCallbackFcn(app, @adjustedParams, true);
            app.offsetfactorSlider.ValueChangingFcn = createCallbackFcn(app, @adjustingT1p_offset, true);
            app.offsetfactorSlider.MinorTicks = [];
            app.offsetfactorSlider.Value = 1;
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.MHzEditField_2Label = uilabel(app.UtilitiesTab);
            app.MHzEditField_2Label.Text = 'MHz';
            app.MHz = uieditfield(app.UtilitiesTab, 'numeric');
            app.MHz.ValueDisplayFormat = '%11.6g';
            app.MHz.ValueChangedFcn = createCallbackFcn(app, @mhz_func, true);
            app.MHz.Value = 14.83;
            app.mTLabel = uilabel(app.UtilitiesTab);
            app.mTLabel.Text = 'mT';
            app.B0mT = uieditfield(app.UtilitiesTab, 'numeric');
            app.B0mT.ValueDisplayFormat = '%11.6g';
            app.B0mT.ValueChangedFcn = createCallbackFcn(app, @gvals_func, true);
            app.B0mT.Value = 348.4;
            app.ConvertLabel = uilabel(app.UtilitiesTab);
            app.ConvertLabel.Text = 'Convert:';
            app.GHzlabel = uilabel(app.UtilitiesTab);
            app.GHzlabel.Text = 'GHz';
            app.GHz = uieditfield(app.UtilitiesTab, 'numeric');
            app.GHz.ValueDisplayFormat = '%11.6g';
            app.GHz.ValueChangedFcn = createCallbackFcn(app, @ghz_func, true);
            app.GHz.Value = 9.778;
            app.gvaluesEditFieldLabel = uilabel(app.UtilitiesTab);
            app.gvaluesEditFieldLabel.Text = 'g values';
            app.gvaluesEditField = uieditfield(app.UtilitiesTab, 'text');
            app.gvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @gvals_func, true);
            app.Label = uilabel(app.xodnpLab);
            app.Label.Text = '';
            app.LoadButton = uibutton(app.xodnpLab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadButton.Text = 'Load';
            app.v31Label = uilabel(app.xodnpLab);
            app.v31Label.Text = 'v3.1';
            % Show the figure after all components are created
            app.xodnpLab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xODNP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.xodnpLab)

            % Execute the startup function
            runStartupFcn(app, @Opening)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.xodnpLab)
        end
    end
end